classdef cameraApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cameraAppUIFigure             matlab.ui.Figure
        ExposuretimemsEditFieldLabel  matlab.ui.control.Label
        ExposureTimeMsEditField       matlab.ui.control.NumericEditField
        ActiveareaDropDownLabel       matlab.ui.control.Label
        ActiveareaDropDown            matlab.ui.control.DropDown
        RunSynchronizedAQButton       matlab.ui.control.Button
        SnapButton                    matlab.ui.control.Button
        SavesettingsButton            matlab.ui.control.Button
        LoadsettingsButton            matlab.ui.control.Button
        QuickSettingsDropDownLabel    matlab.ui.control.Label
        QuickSettingsDropDown         matlab.ui.control.DropDown
        ShutdownButton                matlab.ui.control.Button
        BinningDropDownLabel          matlab.ui.control.Label
        BinningDropDown               matlab.ui.control.DropDown
        OffsetDropDownLabel           matlab.ui.control.Label
        OffsetDropDown                matlab.ui.control.DropDown
        CenteredCheckBox              matlab.ui.control.CheckBox
        UIAxes                        matlab.ui.control.UIAxes
        ImagesizeEditFieldLabel       matlab.ui.control.Label
        ImagesizeEditField            matlab.ui.control.EditField
        ReadouttimemsEditFieldLabel   matlab.ui.control.Label
        ReadouttimemsEditField        matlab.ui.control.NumericEditField
        DefineButton                  matlab.ui.control.Button
        RecordframesEditFieldLabel    matlab.ui.control.Label
        RecordframesEditField         matlab.ui.control.NumericEditField
        ClosefileButton               matlab.ui.control.Button
        ROI_up                        matlab.ui.control.Button
        ROI_left                      matlab.ui.control.Button
        ROI_right                     matlab.ui.control.Button
        ROI_down                      matlab.ui.control.Button
        MoveAmountLabel               matlab.ui.control.Label
        ROI_move_amount               matlab.ui.control.NumericEditField
        ROI_shrink                    matlab.ui.control.Button
    end

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
% 
%   Copyright 2018-2019 Vicente Parot
% 
%   Permission is hereby granted, free of charge, to any person obtaining a
%   copy of this software and associated documentation files (the
%   "Software"), to deal in the Software without restriction, including
%   without limitation the rights to use, copy, modify, merge, publish,
%   distribute, sublicense, and/or sell copies of the Software, and to
%   permit persons to whom the Software is furnished to do so, subject to
%   the following conditions:
% 
%   The above copyright notice and this permission notice shall be included
%   in all copies or substantial portions of the Software. 
% 
%   THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS
%   OR IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF
%   MERCHANTABILITY, FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT.
%   IN NO EVENT SHALL THE AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY
%   CLAIM, DAMAGES OR OTHER LIABILITY, WHETHER IN AN ACTION OF CONTRACT,
%   TORT OR OTHERWISE, ARISING FROM, OUT OF OR IN CONNECTION WITH THE
%   SOFTWARE OR THE USE OR OTHER DEALINGS IN THE SOFTWARE.
%
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %        

    %
    %   2018-2019 Vicente Parot
    %   Cohen Lab - Harvard university
    %
    properties
        fileparams
    end
    
    properties (SetAccess = public)
        previousArea
        previousOffset
        liveArea
        liveExposureTimeSeconds
        liveBinning
        liveOffset
        cameraMaskImg
        sensor_size
    end

    methods (Access = public)
        function readSettings(app,fname,apply)
            %
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            %
            if ~exist('apply','var')
                apply = false;
            end
            if ~exist('fname','var')
                value = app.dfQuickSettingsDropDown.Value;
                qname = [value '.set'];
                [fname, pname, ~] = uigetfile(fullfile('Settings',qname)); % [datestr(now,'YYYY-mm-DD HHMMSS') ' default.set']
                fname = fullfile(pname,fname);
            end
            isOpen = false;
            try
                fid = fopen(fname,'r');
                if fid~=-1
                    isOpen = true;
                end
            catch me
                warning(me.message)
            end
            if isOpen
                event.Source = app;
                event.EventName = 'loadSettings';
                fline = fgetl(fid);
                while (~isequal(fline,-1))
                    propType = '';
                    try
                        txtArray = textscan(fline,'%q');
                        propType = txtArray{1}{1};
                    end
                    switch propType
                        case {'-------',''}
                        case {'logical','numeric'}
                            propName = txtArray{1}{2}(1:end-1);
                            newValue = eval(txtArray{1}{3});
                        case 'char'
                            propName = txtArray{1}{2}(1:end-1);
                            newValue = txtArray{1}{3};
                        case 'cell'
                            propName = txtArray{1}{2}(1:end-1);
                            numLines = eval(txtArray{1}{3});
                            newValue = {};
                            for it = 1:numLines
                                fline = fgetl(fid);
                                txtArray = textscan(fline,'%q');
                                newValue = [newValue; txtArray{1}]; %#ok
                            end
                        otherwise
                            fprintf('unknown property type %s\n',propType)
                            clear newValue
                    end
                    if exist('newValue','var')
                        if isprop(app,propName)
                            parentAppRef = app;
% !                        elseif isprop(app.camApp,propName)
% !                            parentAppRef = app.camApp;
                        else 
                            parentAppRef = [];
                            fprintf('property %s not found\n',propName)
                        end
                        try
                            if ~isempty(parentAppRef) && ~isequal(parentAppRef.(propName).Value,newValue)
                                parentAppRef.(propName).Value = newValue;
                                if apply && ~isempty(parentAppRef.(propName).ValueChangedFcn)
%                                     feval(parentAppRef.(propName).ValueChangedFcn,parentAppRef,event)
                                end
                            end
                        catch me
                            warning('error loading property %s',propName)
                            warning(me.identifier,'%s',me.message)
                        end
                    end
                    fline = fgetl(fid);
                end
                fclose(fid);
            else
                if isempty(fname)
                    error('could not open file to read settings')
                else
                    error('could not open file %s to read settings',fname)
                end
            end
        end

        function writeSettings(app)
            %
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            %
            if ~exist('fname','var')
                value = app.dfQuickSettingsDropDown.Value;
                qname = [value ' camera.set'];
                [fname, pname, ~] = uiputfile(fullfile('Settings',qname)); % [datestr(now,'YYYY-mm-DD HHMMSS') ' default.set']
                fname = fullfile(pname,fname);
            end
            isOpen = false;
            try
                fid = fopen(fname,'w');
                isOpen = true;
            catch me
                warning(me.message)
            end
            if isOpen
                fprintf('writing settings to %s\n',fname)
                dprintf('------- cameraApp settings\n')
                labs = properties(app);
                for it = 1:numel(labs)
                    if numel(app.(labs{it}))==1 && isprop(app.(labs{it}),'Value')
                        if isnumeric(app.(labs{it}).Value)
                            dprintf('numeric %s: %g\n',labs{it},app.(labs{it}).Value)
                        elseif ischar(app.(labs{it}).Value)
                            dprintf('char    %s: "%s"\n',labs{it},app.(labs{it}).Value)
                        elseif islogical(app.(labs{it}).Value)
                            dprintf('logical %s: ',labs{it})
                            if app.(labs{it}).Value
                                dprintf('true\n')
                            else
                                dprintf('false\n')
                            end
                        elseif iscell(app.(labs{it}).Value)
                            dprintf('cell    %s: %d lines',labs{it},numel(app.(labs{it}).Value))
                            for ij = 1:numel(app.(labs{it}).Value)
                                dprintf('\n\t"%s"',app.(labs{it}).Value{ij})
                            end
                            dprintf('\n')
                        else
                            disp(['(unknown property type) ' labs{it} ':'])
                            disp(app.(labs{it}).Value)
                        end
                    else
                    end
                end
                fclose(fid);
            else
                warning('could not open file %s to load settings',fname)
            end
            function dprintf(varargin)
                fprintf(varargin{:});
                fprintf(fid,varargin{:});
            end                        
        end
        function output = validateBinning(app,hEditField)
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            output = app.liveBinning;
            fmtStr = '%d x %d';
            input = sscanf(hEditField.Value,fmtStr);
            if ~isnumeric(input) || isempty(input)
                error('Binning format must be N x N (or just N), with N = 1, 2, or 4')
            else
                input = input(1);
                switch input
                    case {1,2,4}
                        output = input;
                    otherwise
                        fprintf('Binning format must be N x N (or just N), with N = 1, 2, or 4\n')
                end     
            end
            hEditField.Value = sprintf(fmtStr,output,output);
        end
        function output = validateActiveArea(app,hEditField)
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            fmtStr = '%d x %d';
            str = hEditField.Value;
            idx = regexp(str,'[^.0-9]');
            str(idx) = ' ';
            input = sscanf(str,'%f %f');
            input = input(1:min(end,2));
            if ~isnumeric(input) || isempty(input)
                output = app.liveArea;
                fprintf('Area format must have 2 numbers: hSize x vSize\n')
            elseif strcmp(hEditField.Value,'Previous')
                output = app.previousArea;
            else
                output = input;
                if(app.CenteredCheckBox.Value)
                    output = round(output/8)*8;
                    output = max(output,8);
                    output = min(output,app.sensor_size(1));
                else
                    output = round(output/4)*4;
                    output = max(output,4);
                    output = min(output,app.sensor_size(1));
                end
                hEditField.Value = sprintf(fmtStr,output);
            end
        end
        function displayImageSize(app)
            fmtStr = '%d x %d';
            app.ImagesizeEditField.Value = sprintf(fmtStr,app.liveArea/app.liveBinning);
        end
        function output = validateOffset(app,hEditField)
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            fmtStr = '%d, %d';
            str = hEditField.Value;
            idx = regexp(str,'[^.0-9]');
            str(idx) = ' ';
            input = sscanf(str,'%f %f');
            if ~isnumeric(input) || isempty(input)
                output = app.liveOffset;
                fprintf('Offset format must have 2 numbers: hPos, vPos\n')
            else
                output = input;
                output = max(output,0);
                output = min(output,app.sensor_size(1)-app.liveArea);
                hEditField.Value = sprintf(fmtStr,output);
            end
        end
        function [dcimg_fpath, dcimg_fext, bin_temp_fpath, bin_temp_fext, bin_tgt_fpath, bin_tgt_fext] = ...
                makeDoubleSlashFNames(~,dcimg_dir,bin_temp_dir,bin_tgt_dir,rootname)
            [fp, fn, ~] = fileparts(fullfile(dcimg_dir,rootname));
            dcimg_fpath = strrep(fullfile(fp,fn), filesep, [filesep filesep]);
            dcimg_fext = 'dcimg';
            fname = fullfile([dcimg_fpath '.' dcimg_fext]);
            if exist(fname,'file')
                delete(fname)
            end
            [fp, fn, ~] = fileparts(fullfile(bin_temp_dir,rootname));
            bin_temp_fpath = strrep(fullfile(fp,fn), filesep, [filesep filesep]);
            bin_temp_fext = 'bin';
            [fp, fn, ~] = fileparts(fullfile(bin_tgt_dir,rootname));
            bin_tgt_fpath = strrep(fullfile(fp,fn), filesep, [filesep filesep]);
            bin_tgt_fext = 'bin';
        end
        function drawCameraMask(app)
            app.cameraMaskImg = false(app.sensor_size(1));
            currentROI = app.mexROI;
            if app.CenteredCheckBox.Value
                app.cameraMaskImg(...
                    end/2-currentROI(2)/2+(1:currentROI(2)),...
                    end/2-currentROI(1)/2+(1:currentROI(1))) = true;
            else
                app.cameraMaskImg(...
                    currentROI(3)+(1:currentROI(4)),...
                    currentROI(1)+(1:currentROI(2))) = true;
            end
            imshow(app.cameraMaskImg,'Parent',app.UIAxes)
        end    
        function output = mexROI(app)
            if app.CenteredCheckBox.Value
                output = app.liveArea;
            else
                app.liveOffset = app.validateOffset(app.OffsetDropDown);
%                 output = [app.liveOffset(1) app.liveArea(1) app.liveOffset(2) app.liveArea(2)]; %hamamastu
                output = [app.liveOffset(2) app.liveArea(2) app.liveOffset(1) app.liveArea(1)]; % kinetix
            end
        end
    
        function updateActiveArea(app)
            

            newValue = app.validateActiveArea(app.ActiveareaDropDown);
            if strcmp(app.ActiveareaDropDown.Value,'Previous')
                app.ActiveareaDropDown.Value = sprintf('%d x %d',app.previousArea);
                app.updateActiveArea

                app.OffsetDropDown.Value = sprintf('%d, %d',app.previousOffset);
                app.updateOffset
                
            elseif ~isequal(newValue,app.liveArea)
                app.previousArea = app.liveArea;
                
                app.liveArea = newValue;
                if app.CenteredCheckBox.Value
                else
                    app.OffsetDropDown.Value = sprintf('%d, %d',app.validateOffset(app.OffsetDropDown));
                    app.updateOffset
                end
                [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
                % app.ExposureTimeMsEditField.Value = expo*1000;
                app.ReadouttimemsEditField.Value = read*1000;
                app.drawCameraMask
                app.displayImageSize
                
                
            end
            
            
        end
    
        function updateOffset(app)
            newValue = app.validateOffset(app.OffsetDropDown);
            if ~isequal(newValue,app.liveOffset)
                app.previousOffset = app.liveOffset;
                
                app.liveOffset = newValue;
                [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
                % app.ExposureTimeMsEditField.Value = expo*1000;
                app.ReadouttimemsEditField.Value = read*1000;
                app.drawCameraMask
            end
        end
%         function output = isMexROICentered(app)
%             roi = app.mexROI;
%             output = 
%             
%             if app.CenteredCheckBox.Value
%                 output = app.liveArea;
%             else
%                 app.liveOffset = app.validateOffset(app.OffsetDropDown);
%                 output = [app.liveOffset(1) app.liveArea(1) app.liveOffset(2) app.liveArea(2)];
%             end
%         end
    end
    methods
        function img = snapOneFrame(app)
            img = cameraMex('aq_snap')';             
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            % initialize hardware if not available already
            addpath Settings
            addpath Hardware
            try
                cameraSettings
                app.sensor_size = [sensor_width sensor_height];
            catch
                edit cameraSettings
                app.delete
                error('error running settings file')
            end

            % run camera if not already running
%             try 
                clear cameraMex, 
                cameraMex('startup',...
                    liveCameraMonitorIndex, ...
                    liveCameraWindowWidth, ...
                    liveCameraWindowHeight, ...
                    liveCameraWindowOffsetX, ...
                    liveCameraWindowOffsetY);
%             end %#ok % implements singleton instance
            
            % store internal properties that update on chage of controls only
            app.liveArea = app.validateActiveArea(app.ActiveareaDropDown);
            if app.CenteredCheckBox.Value
                app.liveOffset = [0 0]';
            else
                app.liveOffset = app.validateOffset(app.OffsetDropDown);
            end
            app.drawCameraMask
            app.liveBinning = 1;
            app.BinningDropDown.Value = app.BinningDropDown.Items{1};
%             app.liveBinning = app.validateBinning(app.BinningDropDown);
            app.displayImageSize
            
% 
%             % load default gui settings from selected quick setting
%             value = app.QuickSettingsDropDown.Value;
%             fname = [value '.set'];
% %             app.readSettings(fname,true)
%             pause(.5)
%             % go to live imaging mode
            app.liveExposureTimeSeconds = 15e-3;
            app.ExposureTimeMsEditField.Value = app.liveExposureTimeSeconds * 1000;
            [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
%             app.ExposureTimeMsEditField.Value = expo*1000;
%             app.ReadouttimemsEditField.Value = read*1000;

        end

        % Close request function: cameraAppUIFigure
        function closeRequestFcn(app, event)
            delete(app)            
        end

        % Button pushed function: SavesettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.writeSettings
        end

        % Button pushed function: LoadsettingsButton
        function LoadSettingsButtonPushed(app, event)
            app.readSettings
        end

        % Value changed function: ExposureTimeMsEditField
        function ExposureTimeMsEditFieldValueChanged(app, event)
            newValue = app.ExposureTimeMsEditField.Value/1000;
            if ~isequal(newValue,app.liveExposureTimeSeconds)
                app.liveExposureTimeSeconds = newValue;
                [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
                % app.ExposureTimeMsEditField.Value = expo*1000;
                app.ReadouttimemsEditField.Value = read*1000;
            end
        end

        % Value changed function: ActiveareaDropDown
        function ActiveAreaDropDownValueChanged(app, event)
            app.updateActiveArea
%             newValue = app.validateActiveArea(app.ActiveareaDropDown);
%             if ~isequal(newValue,app.liveArea)
%                 app.liveArea = newValue;
%                 if app.CenteredCheckBox.Value
%                 else
%                     app.liveOffset = app.validateOffset(app.OffsetDropDown);
%                 end
%                 [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
%                 % app.ExposureTimeMsEditField.Value = expo*1000;
%                 app.ReadouttimemsEditField.Value = read*1000;
%                 app.drawCameraMask
%                 app.displayImageSize
%             end
        end

        % Button pushed function: RunSynchronizedAQButton
        function RunSynchronizedAQButtonPushed(app, event)
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            recordFrames = app.RecordframesEditField.Value; % 1 extra camera pulse needed
            pixelBytes = 2; % in 16BPP mode
            
            tempdir = fullfile('r:','temp');
            if ~exist(tempdir,'dir')
                try
                    mkdir(tempdir);
                catch
                    error('could not access r:\ drive')
                end
            end
            FileObj      = java.io.File(tempdir);
            usable_bytes = FileObj.getUsableSpace;
            needed_bytes = (app.sensor_size(1) + app.liveArea(1))*app.liveArea(2)*recordFrames*pixelBytes;
            needed_bytes = needed_bytes/app.liveBinning^2;
            if needed_bytes > usable_bytes*.98
                error('not enough space in disk')
            end
            
            if isfield(app.fileparams,'dcimg_dir')
                dcimg_dir = app.fileparams.dcimg_dir;
            else
                dcimg_dir = fullfile('r:',datestr(now,'HHMMSS'));
            end
            if isfield(app.fileparams,'bin_temp_dir')
                bin_temp_dir = app.fileparams.bin_temp_dir;
            else
                bin_temp_dir = dcimg_dir;
            end
            if isfield(app.fileparams,'bin_tgt_dir')
                bin_tgt_dir = app.fileparams.bin_tgt_dir;
            else
                bin_tgt_dir = bin_temp_dir;
            end

%             try app.tim.TimerFcn = ';'; end %#ok % disables the timer if running
            try cameraMex aq_sync_stop, end %#ok % stop to close the file if it was open
            [expo, read] = cameraMex('aq_sync_prepare',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);

%             aqDuration = recordFrames*app.liveExposureTimeSeconds;
            rootname = 'Sq_camera';
            [dcimg_fpath, dcimg_fext, bin_temp_fpath, bin_temp_fext, bin_tgt_fpath, bin_tgt_fext] = ...
                makeDoubleSlashFNames(app,dcimg_dir,bin_temp_dir,bin_tgt_dir,rootname);
            if ~exist(dcimg_dir,'dir')
                mkdir(dcimg_dir)
            end
            if ~exist(bin_temp_dir,'dir')
                mkdir(bin_temp_dir)
            end
            if ~exist(bin_tgt_dir,'dir')
                mkdir(bin_tgt_dir)
            end
            fid = fopen(fullfile(dcimg_dir,'experimental_parameters.txt'),'w');
            fprintf(fid,'Horizontal pixel:_____________	%4d\r\n',app.liveArea(2)/app.liveBinning);
            fprintf(fid,'Vertical pixel:_______________	%4d\r\n',app.liveArea(1)/app.liveBinning);
            fclose(fid);

            cameraMex('aq_sync_start',recordFrames,dcimg_fpath, dcimg_fext, bin_temp_fpath, bin_temp_fext, bin_tgt_fpath, bin_tgt_fext)
               
%             cmdstr = {
%                 '[expo, read] = cameraMex(''aq_live_restart'',app.mexROI,app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);' % live restart includes stop, close file, convert to bin.
%                 'disp(''live mode running'')'
%                 };
%             t = timer(...
%                 'TimerFcn', sprintf('%s',cmdstr{:}),...
%                 'StartDelay',aqDuration,'ExecutionMode','singleShot');
%             t.start % launch timer to close the file automatically
        end

        % Button pushed function: ShutdownButton
        function ShutdownButtonPushed(app, event)
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            try cameraMex shutdown, end
            clear cameraMex
            app.closeRequestFcn(event)
        end

        % Value changed function: CenteredCheckBox
        function CenteredCheckBoxValueChanged(app, event)
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            if app.CenteredCheckBox.Value
                app.OffsetDropDown.Enable = 'off';
                app.OffsetDropDownLabel.Enable = 'off';
                drawnow
                app.liveArea = app.validateActiveArea(app.ActiveareaDropDown);
            else
                app.OffsetDropDown.Enable = 'on';
                app.OffsetDropDownLabel.Enable = 'on';
                drawnow
                app.liveArea = app.validateActiveArea(app.ActiveareaDropDown);
                app.liveOffset = app.validateOffset(app.OffsetDropDown);
            end
            [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
            app.ReadouttimemsEditField.Value = read*1000;
            app.drawCameraMask
            app.displayImageSize
        end

        % Value changed function: BinningDropDown
        function BinningDropDownValueChanged(app, event)
            newValue = app.validateBinning(app.BinningDropDown);
            if ~isequal(newValue,app.liveBinning)
                app.liveBinning = newValue;
                [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
                app.ReadouttimemsEditField.Value = read*1000;
                app.displayImageSize
            end
        end

        % Value changed function: OffsetDropDown
        function OffsetDropDownValueChanged(app, event)
            % assume roi is not centered
            app.updateOffset
%             newValue = app.validateOffset(app.OffsetDropDown);
%             if ~isequal(newValue,app.liveOffset)
%                 app.liveOffset = newValue;
%                 [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
%                 % app.ExposureTimeMsEditField.Value = expo*1000;
%                 app.ReadouttimemsEditField.Value = read*1000;
%                 app.drawCameraMask
%             end
        end

        % Value changed function: QuickSettingsDropDown
        function QuickSettingsDropDownValueChanged(app, event)
            value = app.QuickSettingsDropDown.Value;
            fname = [value '.set'];
            app.readSettings(fname,true)
        end

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
            img = app.snapOneFrame;             
            imwrite(img,fullfile([datestr(now,'HHMMSS') ' snap.tif']))
            figure(999)
            imshow(img,[])   
            % do more stuff with your img
            % maybe save here?
            % [fname, pname, ~] = uiputfile(fullfile([datestr(now,'YYYY-mm-DD HHMMSS') ' snap.tif']));
            % imwrite(img,fullfile(pname,fname))
        end

        % Button pushed function: DefineButton
        function DefineButtonPushed(app, event)
            img = cameraMex('aq_snap')';
%             imwrite(img,fullfile([datestr(now,'HHMMSS') ' define.tif']))
            figure(999)
            imshow(img,[],'initialmagnification','fit')
            try
                myrect = getrect;   
            end
            if exist('myrect','var') && isnumeric(myrect) && numel(myrect)==4
                line = myrect(1:2)+myrect(3:4).*[0 0 1 1 0; 0 1 1 0 0]';
                hold on
                plot(line(:,1),line(:,2))
                hold off
                saveas(gcf,fullfile([datestr(now,'HHMMSS') ' define.fig']))
                myrect = myrect*app.liveBinning;
                if app.CenteredCheckBox.Value
                    currentOffset = ([app.sensor_size(1) app.sensor_size(1)] - app.liveArea(:)')/2;
                else
                    currentOffset = app.liveOffset(:)';
                end
                myrect(1:2) = currentOffset + myrect(1:2);
                myrect = round(myrect);
                    app.CenteredCheckBox.Value = false;
                    app.OffsetDropDown.Enable = 'on';
                    app.OffsetDropDownLabel.Enable = 'on';
                app.ActiveareaDropDown.Value = sprintf('%d x %d',myrect(3:4));
                app.ActiveAreaDropDownValueChanged
                app.OffsetDropDown.Value = sprintf('%d, %d',myrect(1:2));
                app.OffsetDropDownValueChanged
%                 [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
                % app.ExposureTimeMsEditField.Value = expo*1000;
%                 app.ReadouttimemsEditField.Value = read*1000;
%                 app.drawCameraMask
                app.displayImageSize
            end
        end

        % Button pushed function: ClosefileButton
        function ClosefileButtonPushed(app, event)
            [expo, read] = cameraMex('aq_live_restart',app.mexROI,app.liveBinning,app.liveExposureTimeSeconds);
        end

        % Button pushed function: ROI_up
        function ROI_upButtonPushed(app, event)
            ROI = str2num(app.OffsetDropDown.Value);
            ROI(2) = ROI(2)-app.ROI_move_amount.Value;
            app.OffsetDropDown.Value = sprintf('%i, %i',ROI);
            app.OffsetDropDownValueChanged;
            
        end

        % Button pushed function: ROI_left
        function ROI_leftButtonPushed(app, event)
            ROI = str2num(app.OffsetDropDown.Value);
            ROI(1) = ROI(1)-app.ROI_move_amount.Value;
            app.OffsetDropDown.Value = sprintf('%i, %i',ROI);
            app.OffsetDropDownValueChanged;
        end

        % Button pushed function: ROI_right
        function ROI_rightButtonPushed(app, event)
            ROI = str2num(app.OffsetDropDown.Value);
            ROI(1) = ROI(1)+app.ROI_move_amount.Value;
            app.OffsetDropDown.Value = sprintf('%i, %i',ROI);
            app.OffsetDropDownValueChanged;
        end

        % Button pushed function: ROI_down
        function ROI_downButtonPushed(app, event)
            ROI = str2num(app.OffsetDropDown.Value);
            ROI(2) = ROI(2)+app.ROI_move_amount.Value;
            app.OffsetDropDown.Value = sprintf('%i, %i',ROI);
            app.OffsetDropDownValueChanged;
        end

        % Button pushed function: ROI_shrink
        function ROI_shrinkButtonPushed(app, event)
            offset = str2num(app.OffsetDropDown.Value);
            offset = offset-100;
            app.OffsetDropDown.Value = sprintf('%f, %f', offset);
            app.OffsetDropDownValueChanged;
            
            ROI = sscanf(app.ActiveareaDropDown.Value,'%f x %f');
            ROI = ROI+200;
            app.ActiveareaDropDown.Value = sprintf('%d x %d',ROI);
            app.ActiveAreaDropDownValueChanged;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.cameraAppUIFigure = uifigure;
            app.cameraAppUIFigure.Name = 'cameraApp';
            app.cameraAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequestFcn, true);
            app.ExposuretimemsEditFieldLabel = uilabel(app.cameraAppUIFigure);
            app.ExposuretimemsEditFieldLabel.Text = 'Exposure time (ms)';
            app.ExposureTimeMsEditField = uieditfield(app.cameraAppUIFigure, 'numeric');
            app.ExposureTimeMsEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeMsEditFieldValueChanged, true);
            app.ExposureTimeMsEditField.Value = 150;
            app.ActiveareaDropDownLabel = uilabel(app.cameraAppUIFigure);
            app.ActiveareaDropDownLabel.Text = 'Active area';
            app.ActiveareaDropDown = uidropdown(app.cameraAppUIFigure);
            app.ActiveareaDropDown.Items = {'Previous', '2048 x 2048', '2048 x 1024', '1536 x 1024', '1024 x 1024', '512 x 512', '256 x 256', '2048 x 160', '1024 x 160'};
            app.ActiveareaDropDown.Editable = 'on';
            app.ActiveareaDropDown.ValueChangedFcn = createCallbackFcn(app, @ActiveAreaDropDownValueChanged, true);
            app.ActiveareaDropDown.Value = '2048 x 2048';
            app.RunSynchronizedAQButton = uibutton(app.cameraAppUIFigure, 'push');
            app.RunSynchronizedAQButton.ButtonPushedFcn = createCallbackFcn(app, @RunSynchronizedAQButtonPushed, true);
            app.RunSynchronizedAQButton.Text = 'Run synchronized AQ';
            app.SnapButton = uibutton(app.cameraAppUIFigure, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Text = 'Snap';
            app.SavesettingsButton = uibutton(app.cameraAppUIFigure, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.LoadsettingsButton = uibutton(app.cameraAppUIFigure, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.QuickSettingsDropDownLabel = uilabel(app.cameraAppUIFigure);
            app.QuickSettingsDropDownLabel.Text = 'Quick Settings';
            app.QuickSettingsDropDown = uidropdown(app.cameraAppUIFigure);
            app.QuickSettingsDropDown.Items = {'Optopatch', 'Hadamard', 'RCaMPtopatch', 'SimpleMovie'};
            app.QuickSettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @QuickSettingsDropDownValueChanged, true);
            app.QuickSettingsDropDown.Value = 'RCaMPtopatch';
            app.ShutdownButton = uibutton(app.cameraAppUIFigure, 'push');
            app.ShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownButtonPushed, true);
            app.ShutdownButton.Text = 'Shutdown';
            app.BinningDropDownLabel = uilabel(app.cameraAppUIFigure);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.cameraAppUIFigure);
            app.BinningDropDown.Items = {'1 x 1', '2 x 2', '4 x 4'};
            app.BinningDropDown.Editable = 'on';
            app.BinningDropDown.ValueChangedFcn = createCallbackFcn(app, @BinningDropDownValueChanged, true);
            app.BinningDropDown.Value = '1 x 1';
            app.OffsetDropDownLabel = uilabel(app.cameraAppUIFigure);
            app.OffsetDropDownLabel.Enable = 'off';
            app.OffsetDropDownLabel.Text = 'Offset';
            app.OffsetDropDown = uidropdown(app.cameraAppUIFigure);
            app.OffsetDropDown.Items = {'512, 512', '1536, 1536'};
            app.OffsetDropDown.Editable = 'on';
            app.OffsetDropDown.ValueChangedFcn = createCallbackFcn(app, @OffsetDropDownValueChanged, true);
            app.OffsetDropDown.Enable = 'off';
            app.OffsetDropDown.Value = '512, 512';
            app.CenteredCheckBox = uicheckbox(app.cameraAppUIFigure);
            app.CenteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @CenteredCheckBoxValueChanged, true);
            app.CenteredCheckBox.Text = 'Centered';
            app.CenteredCheckBox.Value = true;
            app.UIAxes = uiaxes(app.cameraAppUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImagesizeEditFieldLabel = uilabel(app.cameraAppUIFigure);
            app.ImagesizeEditFieldLabel.Text = 'Image size';
            app.ImagesizeEditField = uieditfield(app.cameraAppUIFigure, 'text');
            app.ImagesizeEditField.Editable = 'off';
            app.ImagesizeEditField.Value = '2048 x 2048';
            app.ReadouttimemsEditFieldLabel = uilabel(app.cameraAppUIFigure);
            app.ReadouttimemsEditFieldLabel.Text = 'Readout time (ms)';
            app.ReadouttimemsEditField = uieditfield(app.cameraAppUIFigure, 'numeric');
            app.ReadouttimemsEditField.Editable = 'off';
            app.DefineButton = uibutton(app.cameraAppUIFigure, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @DefineButtonPushed, true);
            app.DefineButton.Text = 'Define';
            app.RecordframesEditFieldLabel = uilabel(app.cameraAppUIFigure);
            app.RecordframesEditFieldLabel.Text = 'Record frames';
            app.RecordframesEditField = uieditfield(app.cameraAppUIFigure, 'numeric');
            app.RecordframesEditField.Limits = [1 Inf];
            app.RecordframesEditField.RoundFractionalValues = 'on';
            app.RecordframesEditField.Value = 1;
            app.ClosefileButton = uibutton(app.cameraAppUIFigure, 'push');
            app.ClosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ClosefileButtonPushed, true);
            app.ClosefileButton.Text = {'Close'; 'file'};
            app.ROI_up = uibutton(app.cameraAppUIFigure, 'push');
            app.ROI_up.ButtonPushedFcn = createCallbackFcn(app, @ROI_upButtonPushed, true);
            app.ROI_up.Icon = 'up_icon.png';
            app.ROI_up.Text = '';
            app.ROI_left = uibutton(app.cameraAppUIFigure, 'push');
            app.ROI_left.ButtonPushedFcn = createCallbackFcn(app, @ROI_leftButtonPushed, true);
            app.ROI_left.Icon = 'left_icon.png';
            app.ROI_left.Text = '';
            app.ROI_right = uibutton(app.cameraAppUIFigure, 'push');
            app.ROI_right.ButtonPushedFcn = createCallbackFcn(app, @ROI_rightButtonPushed, true);
            app.ROI_right.Icon = 'right_icon.png';
            app.ROI_right.Text = '';
            app.ROI_down = uibutton(app.cameraAppUIFigure, 'push');
            app.ROI_down.ButtonPushedFcn = createCallbackFcn(app, @ROI_downButtonPushed, true);
            app.ROI_down.Icon = 'down_icon.png';
            app.ROI_down.Text = '';
            app.MoveAmountLabel = uilabel(app.cameraAppUIFigure);
            app.MoveAmountLabel.Text = 'Move Amount';
            app.ROI_move_amount = uieditfield(app.cameraAppUIFigure, 'numeric');
            app.ROI_move_amount.Limits = [0 2048];
            app.ROI_move_amount.ValueDisplayFormat = '%.0f';
            app.ROI_move_amount.Value = 50;
            app.ROI_shrink = uibutton(app.cameraAppUIFigure, 'push');
            app.ROI_shrink.ButtonPushedFcn = createCallbackFcn(app, @ROI_shrinkButtonPushed, true);
            app.ROI_shrink.Text = '-';
        end
    end

    methods (Access = public)

        % Construct app
        function app = cameraApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.cameraAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cameraAppUIFigure)
        end
    end
end