classdef dragonflyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dragonflyAppUIFigure            matlab.ui.Figure
        DMDlivedisplayButtonGroup       matlab.ui.container.ButtonGroup
        AllOFFButton                    matlab.ui.control.ToggleButton
        AllONButton                     matlab.ui.control.ToggleButton
        ManualROIsButton                matlab.ui.control.ToggleButton
        RegisterButton                  matlab.ui.control.ToggleButton
        SemiautoROIsButton              matlab.ui.control.ToggleButton
        AddROIsButton                   matlab.ui.control.ToggleButton
        DeleteROIsButton                matlab.ui.control.ToggleButton
        ResetButton                     matlab.ui.control.ToggleButton
        RectangleButton                 matlab.ui.control.ToggleButton
        FocusTestButton                 matlab.ui.control.ToggleButton
        DMDstatusButton                 matlab.ui.control.StateButton
        SliceSpinnerLabel               matlab.ui.control.Label
        SliceSpinner                    matlab.ui.control.Spinner
        FOVSpinnerLabel                 matlab.ui.control.Label
        FOVSpinner                      matlab.ui.control.Spinner
        appendedfoldernameEditFieldLabel  matlab.ui.control.Label
        appendedfoldernameEditField     matlab.ui.control.EditField
        RunSynchronizedAQButton         matlab.ui.control.Button
        SnapButton                      matlab.ui.control.Button
        NSpinnerLabel                   matlab.ui.control.Label
        NSpinner                        matlab.ui.control.Spinner
        SpacingumEditFieldLabel         matlab.ui.control.Label
        SpacingumEditField              matlab.ui.control.NumericEditField
        FilterDropDownLabel             matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        SLMlivedisplayButtonGroup       matlab.ui.container.ButtonGroup
        CalibrationButton               matlab.ui.control.ToggleButton
        SheetButton                     matlab.ui.control.ToggleButton
        DotsButton_2                    matlab.ui.control.ToggleButton
        SLMstatusButton                 matlab.ui.control.StateButton
        ZstackButton                    matlab.ui.control.StateButton
        BlueLabel                       matlab.ui.control.Label
        LimeLabel                       matlab.ui.control.Label
        SnapLimeONCheckBox              matlab.ui.control.CheckBox
        SnapLimeOFFCheckBox             matlab.ui.control.CheckBox
        RedLabel                        matlab.ui.control.Label
        SnapRedONCheckBox               matlab.ui.control.CheckBox
        SnapRedOFFCheckBox              matlab.ui.control.CheckBox
        WithSnapLabel                   matlab.ui.control.Label
        Blue460nmButton                 matlab.ui.control.StateButton
        Lime561nmButton                 matlab.ui.control.StateButton
        Red640nmButton                  matlab.ui.control.StateButton
        SavesettingsButton              matlab.ui.control.Button
        LoadsettingsButton              matlab.ui.control.Button
        SnapBlueONCheckBox              matlab.ui.control.CheckBox
        SnapBlueOFFCheckBox             matlab.ui.control.CheckBox
        QuickSettingsDropDownLabel      matlab.ui.control.Label
        dfQuickSettingsDropDown         matlab.ui.control.DropDown
        AmpVLabel                       matlab.ui.control.Label
        BlueAmpVEditField               matlab.ui.control.NumericEditField
        PowermWLabel                    matlab.ui.control.Label
        LimePowerEditField              matlab.ui.control.NumericEditField
        LimeLaserstatusButton           matlab.ui.control.StateButton
        GotoSpinnerLabel                matlab.ui.control.Label
        GotoSpinner                     matlab.ui.control.Spinner
        SetFirstZEditField              matlab.ui.control.NumericEditField
        SetLastZEditField               matlab.ui.control.NumericEditField
        WaveformEditField               matlab.ui.control.EditField
        FramesEditFieldLabel            matlab.ui.control.Label
        camFramesInEditField            matlab.ui.control.NumericEditField
        PicturesLabel                   matlab.ui.control.Label
        camPicturesOutEditField         matlab.ui.control.NumericEditField
        WaveformPreviewButton           matlab.ui.control.Button
        ExposureTimeMsEditField_waveformLabel  matlab.ui.control.Label
        ExposureTimeMsEditField_waveform  matlab.ui.control.NumericEditField
        ROIDropDown_waveformLabel       matlab.ui.control.Label
        ROIDropDown_waveform            matlab.ui.control.DropDown
        ActivateWaveformExposureTimeCheckBox  matlab.ui.control.CheckBox
        ActivateWaveformROICheckBox     matlab.ui.control.CheckBox
        ActivateWaveformCameraFramesCheckBox  matlab.ui.control.CheckBox
        nCellsEditFieldLabel            matlab.ui.control.Label
        nCellsEditField                 matlab.ui.control.NumericEditField
        mWcm2Label                      matlab.ui.control.Label
        liveBluePowerEditField          matlab.ui.control.NumericEditField
        ShutdownButton                  matlab.ui.control.Button
        PatternsEditField               matlab.ui.control.EditField
        PatternsLampLabel               matlab.ui.control.Label
        PatternsLamp                    matlab.ui.control.Lamp
        WaveformLampLabel               matlab.ui.control.Label
        WaveformLamp                    matlab.ui.control.Lamp
        PatternsPreviewButton           matlab.ui.control.Button
        PatternsLabel                   matlab.ui.control.Label
        dmdPatternsOutEditField         matlab.ui.control.NumericEditField
        DMDTriggersLabel                matlab.ui.control.Label
        dmdTrigsOutEditField            matlab.ui.control.NumericEditField
        SetFirstZButton                 matlab.ui.control.Button
        SetLastZButton_2                matlab.ui.control.Button
        AcquisitionOptionsButtonGroup   matlab.ui.container.ButtonGroup
        L1PatternButton_2               matlab.ui.control.RadioButton
        HadamardButton                  matlab.ui.control.RadioButton
        RegularButton                   matlab.ui.control.RadioButton
        HadamardSequenceDropDownLabel   matlab.ui.control.Label
        HadamardSequenceDropDown        matlab.ui.control.DropDown
        FrametimemsEditFieldLabel       matlab.ui.control.Label
        FrametimemsEditField            matlab.ui.control.NumericEditField
        IlluminationColorDropDownLabel  matlab.ui.control.Label
        IlluminationColorDropDown       matlab.ui.control.DropDown
        LoadCellROIsButton              matlab.ui.control.Button
        Orange594nmButton               matlab.ui.control.StateButton
        RepeatEditFieldLabel            matlab.ui.control.Label
        RepeatEditField                 matlab.ui.control.NumericEditField
        WaitTimesEditFieldLabel         matlab.ui.control.Label
        WaitTimesEditField              matlab.ui.control.NumericEditField
        SnapTextArea                    matlab.ui.control.TextArea
        PololuButton                    matlab.ui.control.StateButton
        ODDropDownLabel                 matlab.ui.control.Label
        ODDropDown                      matlab.ui.control.DropDown
    end

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
% 
%   Copyright 2018-2019 Vicente Parot
% 
%   Permission is hereby granted, free of charge, to any person obtaining a
%   copy of this software and associated documentation files (the
%   "Software"), to deal in the Software without restriction, including
%   without limitation the rights to use, copy, modify, merge, publish,
%   distribute, sublicense, and/or sell copies of the Software, and to
%   permit persons to whom the Software is furnished to do so, subject to
%   the following conditions:
% 
%   The above copyright notice and this permission notice shall be included
%   in all copies or substantial portions of the Software. 
% 
%   THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS
%   OR IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF
%   MERCHANTABILITY, FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT.
%   IN NO EVENT SHALL THE AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY
%   CLAIM, DAMAGES OR OTHER LIABILITY, WHETHER IN AN ACTION OF CONTRACT,
%   TORT OR OTHERWISE, ARISING FROM, OUT OF OR IN CONNECTION WITH THE
%   SOFTWARE OR THE USE OR OTHER DEALINGS IN THE SOFTWARE.
%
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %        

    %
    %   2018-2019 Vicente Parot
    %   Cohen Lab - Harvard university
    %
    properties (Access = public)
%         hFig % container for APT controls
%         hAPT_Z
%         hAPT_FilterWheel
        ni6343 % nidaq object to handle output signals
        limeLaser % Description
        hMotorsFig
        motorZ
        motorW
        camApp
        dmdApp
        dmd
        ODWheel % Description
        
        tim % Tim the timer
        liveCenteredROI
        liveExposureTimeSeconds
        waveformCenteredROIInput
        waveformExposureTimeSecondsInput
        waveformCameraFramesInput
        wavesData % holds synchronized waveform data after being generated
        patternData % Description
        stackZPositions % Description
        registration_data
        save_dir
        cur_dir% Description
        dmd_rois % Description
        dmd_pat % Description
        cell_rois % Description
        
    end
    
    methods (Access = public)
        function readSettings(app,fname,apply)
            %
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            %
            if ~exist('apply','var')
                apply = false;
            end
            if ~exist('fname','var') || isempty(fname)
                value = app.dfQuickSettingsDropDown.Value;
                qname = [value '.set'];
                [fname, pname, ~] = uigetfile(fullfile('Settings',qname)); % [datestr(now,'YYYY-mm-DD HHMMSS') ' default.set']
                fname = fullfile(pname,fname);
            end
            isOpen = false;
            try
                fid = fopen(fname,'r');
                if fid~=-1
                    isOpen = true;
                end
            catch me
                warning(me.message)
            end
            if isOpen
                event.Source = app;
                event.EventName = 'loadSettings';
                fline = fgetl(fid);
                while (~isequal(fline,-1))
                    propType = '';
                    try %#okay no catch
                        txtArray = textscan(fline,'%q');
                        propType = txtArray{1}{1};
                    end
                    switch propType
                        case {'-------',''}
                        case {'logical','numeric'}
                            propName = txtArray{1}{2}(1:end-1);
                            newValue = eval(txtArray{1}{3});
                        case 'char'
                            propName = txtArray{1}{2}(1:end-1);
                            newValue = txtArray{1}{3};
                        case 'cell'
                            propName = txtArray{1}{2}(1:end-1);
                            numLines = eval(txtArray{1}{3});
                            newValue = {};
                            for it = 1:numLines
                                fline = fgetl(fid);
                                txtArray = textscan(fline,'%q');
                                newValue = [newValue; txtArray{1}];  %#okay grows
                            end
                        otherwise
                            fprintf('unknown property type %s\n',propType)
                            clear newValue
                    end
                    if exist('newValue','var')
                        if isprop(app,propName)
                            parentAppRef = app;
                        elseif isprop(app.camApp,propName)
                            parentAppRef = app.camApp;
                        else 
                            parentAppRef = [];
                            fprintf('property %s not found\n',propName)
                        end
                        try
                            if ~isempty(parentAppRef) && ~isequal(parentAppRef.(propName).Value,newValue)
                                parentAppRef.(propName).Value = newValue;
                                if apply && ...
                                         numel(parentAppRef.(propName))==1 && ...
                                         isprop(parentAppRef.(propName),'ValueChangedFcn') && ...
                                        ~isempty(parentAppRef.(propName).ValueChangedFcn) && ...
                                        ~isequal(propName,'dfQuickSettingsDropDown') && ...
                                        ~isequal(propName,'GotoSpinner')
                                    feval(parentAppRef.(propName).ValueChangedFcn,parentAppRef,event)
                                end
                            end
                        catch me
                            warning('error loading property %s\n%s',propName,me.message)
                        end
                    end
                    fline = fgetl(fid);
                end
                fclose(fid);
            else
                if isempty(fname)
                    error('could not open file to read settings')
                else
                    error('could not open file %s to read settings',fname)
                end
            end
        end
        function writeSettings(app,fname)
            %
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            %
            if ~exist('fname','var')
                value = app.dfQuickSettingsDropDown.Value;
                qname = [value '.set'];
                [fname, pname, ~] = uiputfile(fullfile('Settings',qname)); % [datestr(now,'YYYY-mm-DD HHMMSS') ' default.set']
                fname = fullfile(pname,fname);
            end
            isOpen = false;
            try
                fid = fopen(fname,'w');
                isOpen = true;
            catch me
                warning(me.message)
            end
            if isOpen
                dprintf('------- dragonflyApp settings\n')
                crawlWriteFromApp(app)
                dprintf('\n')
                dprintf('------- cameraApp settings\n')
                crawlWriteFromApp(app.camApp)
                fclose(fid);
                fprintf('settings saved to %s\n',fname)
            else
                warning('could not open file %s to write settings',fname)
            end
            function dprintf(varargin)
                % fprintf(varargin{:});
                fprintf(fid,varargin{:});
            end        
            function crawlWriteFromApp(app)
                labs = properties(app);
                for it = 1:numel(labs)
                    if numel(app.(labs{it}))==1 && isprop(app.(labs{it}),'Value')
                        if isnumeric(app.(labs{it}).Value)
                            dprintf('numeric %s: %g\n',labs{it},app.(labs{it}).Value)
                        elseif ischar(app.(labs{it}).Value)
                            dprintf('char    %s: "%s"\n',labs{it},app.(labs{it}).Value)
                        elseif islogical(app.(labs{it}).Value)
                            dprintf('logical %s: ',labs{it})
                            if app.(labs{it}).Value
                                dprintf('true\n')
                            else
                                dprintf('false\n')
                            end
                        elseif iscell(app.(labs{it}).Value)
                            dprintf('cell    %s: %d lines',labs{it},numel(app.(labs{it}).Value))
                            for ij = 1:numel(app.(labs{it}).Value)
                                dprintf('\n\t"%s"',app.(labs{it}).Value{ij})
                            end
                            dprintf('\n')
                        else
                            disp(['(unknown property type) ' labs{it} ':'])
                            disp(app.(labs{it}).Value)
                        end
                    else
    %                     disp(labs{it})
                    end
                end
            end
        end
        
        function updateSpacing(app)
            app.stackZPositions = linspace(app.SetFirstZEditField.Value,app.SetLastZEditField.Value,app.NSpinner.Value);
            app.SpacingumEditField.Value = mean(diff(app.stackZPositions))*1000;                        
        end
        
        function centeredROI = validateROI(~,hROIEditField)
            numROI = sscanf(hROIEditField.Value,'%d x %d');
            if ~isnumeric(numROI) || ~isequal(size(numROI),[2 1])
                error('ROI format must be number x number')
            else
                numROI = round(numROI/8)*8;
                numROI = max(numROI,8);
                numROI = min(numROI,2048);
                centeredROI = [numROI(1) numROI(2)];
            end
            hROIEditField.Value = sprintf('%d x %d',centeredROI(1),centeredROI(2));
        end
        function [dcimg_fpath, dcimg_fext, bin_temp_fpath, bin_temp_fext, bin_tgt_fpath, bin_tgt_fext] = ...
                makeDoubleSlashFNames(~,dcimg_dir,bin_temp_dir,bin_tgt_dir,rootname)
            [fp, fn, ~] = fileparts(fullfile(dcimg_dir,rootname));
            dcimg_fpath = strrep(fullfile(fp,fn), filesep, [filesep filesep]);
            dcimg_fext = 'dcimg';
            fname = fullfile([dcimg_fpath '.' dcimg_fext]);
            if exist(fname,'file')
                delete(fname)
            end
            [fp, fn, ~] = fileparts(fullfile(bin_temp_dir,rootname));
            bin_temp_fpath = strrep(fullfile(fp,fn), filesep, [filesep filesep]);
            bin_temp_fext = 'bin';
            [fp, fn, ~] = fileparts(fullfile(bin_tgt_dir,rootname));
            bin_tgt_fpath = strrep(fullfile(fp,fn), filesep, [filesep filesep]);
            bin_tgt_fext = 'bin';
        end
        
        function color = lampColor(app,status)
            lampOnColor = [0 1 0];
            if status
                color = lampOnColor;
            else
                color = lampOnColor*.1 + app.dragonflyAppUIFigure.Color*.9;
            end
        end
        
        function allWaves = generateWaveform(app)
            fprintf('generating waveforms ... \n')
            if app.ActivateWaveformExposureTimeCheckBox.Value
                exposureTime = app.waveformExposureTimeSecondsInput;
            else
                exposureTime = app.liveExposureTimeSeconds;
            end
            if app.ActivateWaveformROICheckBox.Value
                centeredROI = app.waveformCenteredROIInput;
            else
                centeredROI = app.liveCenteredROI;
            end
            
            switch app.IlluminationColorDropDown.Value
                case 'Blue'
                    icolor = 'Blue';
                case 'Lime'
                    icolor = 'Lime';
                case 'Red'
                    %under construction
            end
            nCells = app.nCellsEditField.Value;
            maxBlueIntensity = app.BlueAmpVEditField.Value;
            clear(app.WaveformEditField.Value)
            waveFun = str2func(app.WaveformEditField.Value);
            
            selectedButton = app.AcquisitionOptionsButtonGroup.SelectedObject;
            text = selectedButton.Text;
            switch text
                case 'Regular'
                    recordFrames = app.camFramesInEditField.Value;
%                     allWaves = waveFun(nCells,recordFrames,icolor,maxBlueIntensity,centeredROI,app.camApp.ReadouttimemsEditField.Value);
                    allWaves = waveFun(nCells,recordFrames,icolor,maxBlueIntensity,centeredROI,exposureTime);
                case 'Hadamard'
                    m_q = str2num(app.HadamardSequenceDropDown.Value);
                    recordFrames = m_q(1)+1;
                    sline = 1024;
                    app.FrametimemsEditField.Value = exposureTime+sline*10e-3;
                    allWaves = waveFun(nCells,recordFrames,icolor,maxBlueIntensity,centeredROI,exposureTime);
                case 'L1 Pattern'
                    recordFrames = app.camFramesInEditField.Value;
                    allWaves = waveFun(recordFrames,icolor,maxBlueIntensity,exposureTime);
            end

            app.wavesData = allWaves.amplitude';
            app.camPicturesOutEditField.Value = nnz(diff([0 allWaves.amplitude(1,:)])>0)-1;
            app.dmdTrigsOutEditField.Value = nnz(diff([0 allWaves.amplitude(2,:)])>0);
            try
            app.camApp.RecordframesEditField.Value = app.camPicturesOutEditField.Value;
            catch
                disp('Camera closed')
            end
            app.WaveformLamp.Color = app.lampColor(true);     
            fprintf('done\n')
        end
        function niDuration = queueWaveform(app)
            niDuration = app.ni6343.syncQueueData(app.wavesData);
            app.WaveformLamp.Color = app.lampColor(true);     
        end
        function generatePatterns(app)
            fprintf('generating patterns ... \n')
            patFun = str2func(app.PatternsEditField.Value);
            selectedButton = app.AcquisitionOptionsButtonGroup.SelectedObject;
            if iscell(selectedButton.Text), text = [selectedButton.Text{:} ];else, text = selectedButton.Text;end
            switch text
                case 'L1 Pattern'
                    app.patternData = patFun(app.camApp.liveOffset,app.camApp.liveArea,app.cell_rois,app.dmd,app.registration_data);
                case 'Hadamard'
                    m_q = str2num(app.HadamardSequenceDropDown.Value);
                    app.patternData = patFun(app.dmd,m_q,app.dmd_pat);
                case 'Regular'
                    app.patternData = patFun(app.camApp.liveOffset,app.camApp.liveArea,app.cell_rois,app.dmd,app.registration_data);
            end
            app.dmdPatternsOutEditField.Value = size(app.patternData,3);
            fprintf([8 'done\n'])
        end
        function loadPatterns(app)
            if isempty(app.patternData)
                generatePatterns(app)
            end
            app.dmd.load_sequence(app.patternData);
            app.PatternsLamp.Color = app.lampColor(true);     
        end        
        
        function logStartup(app) %#okay not static
            fid = fopen(fullfile(['log_' datestr(now,'yyyymmdd_HHMMSS') '.txt']),'a');
            if (fid ~= -1)
                dprintf('%s ',datestr(now,'YYYY-mm-DD'))
                dprintf('offset activeArea center? exposure bin   imgSize  filter stack?   firstZ    lastZ   N spacing currentZ')
                dprintf('\n')
                fclose(fid);
            else
                warning('could not open log file')
            end
            function dprintf(varargin)
%                 fprintf(varargin{:});
                fprintf(fid,varargin{:});
            end        
        end
    
        function logImgParams(app,str_now)
            fname = dir('log*'); fname = fname.name;
            fid = fopen(fname,'a');
            if (fid ~= -1)
                dprintf('%s ',str_now) % timestamp
                try
                dprintf('(%4d,%4d) ',app.camApp.liveOffset) % (offset) 
                dprintf('%4dx%4d ',app.camApp.liveArea) % activeArea 
                dprintf('      %d ',app.camApp.CenteredCheckBox.Value) % centered? 
                dprintf('    %4g ',app.camApp.liveExposureTimeSeconds*1000) % exposure 
                dprintf('  %d ',app.camApp.liveBinning) % binning 
                dprintf('%4dx%4d ',app.camApp.liveArea/app.camApp.liveBinning) % imgSize
                catch
                    disp('Camera closed\n')
                end
                dprintf('%7s ',app.FilterDropDown.Value) % filter
                dprintf('     %d ',app.ZstackButton.Value) % stack?
                dprintf('%8.4f ',app.SetFirstZEditField.Value) % firstZ 
                dprintf('%8.4f ',app.SetLastZEditField.Value) % lastZ 
                dprintf('%3d ',app.NSpinner.Value) % N 
                dprintf('%7.3f ',app.SpacingumEditField.Value) % spacing
                dprintf('%8.4f ',app.motorZ.position) % lastZ 
                dprintf('\n')

                fclose(fid);
            else
                warning('could not open log file')
            end
            function dprintf(varargin)
%                 fprintf(varargin{:});
                fprintf(fid,varargin{:});
            end        
        end
        function img = snapOnePicture(app)
            if app.SnapBlueONCheckBox.Value
                app.ni6343.blueIntensity = app.BlueAmpVEditField.Value;
                app.Blue460nmButton.Value = true;
            end
            if app.SnapLimeONCheckBox.Value
                app.ni6343.limeShutter = true;
                app.Lime561nmButton.Value = true;
            end
            if app.SnapRedONCheckBox.Value
                app.ni6343.redShutter = true;
                app.Red640nmButton.Value = true;
           end

            pause(max(.04,app.camApp.liveExposureTimeSeconds))
            img = app.camApp.snapOneFrame;
            
            if app.SnapBlueOFFCheckBox.Value
                app.ni6343.blueIntensity = 0;
                app.Blue460nmButton.Value = false;
            end
            if app.SnapLimeOFFCheckBox.Value
                app.ni6343.limeShutter = false;
                app.Lime561nmButton.Value = false;
            end
            if app.SnapRedOFFCheckBox.Value
                app.ni6343.redShutter = false;
                app.Red640nmButton.Value = false;
            end
        end
        
        function move_file(app)
            if isempty(app.save_dir) || isempty(app.SaveNameTextArea.Value)
                return
            end
            cd('R:\')
            r_folder = dir('*p1');
            movefile(r_folder,app.save_dir)
            cd(app.save_dir);
            
            if iscell(selectedButton.Text), text = [selectedButton.Text{:} ];else, text = selectedButton.Text;end
            switch text
                case 'Calibration(Hadamard)'
                    movefile(r_folder.name,'hadamard_calibration');
                case 'Hadamard'
                    movefile(r_folder.name,[app.SaveNameTextArea.Value '_hadamard']);
                case 'Regular'
                    movefile(r_folder.name,app.SaveNameTextArea.Value);
            end
            cd(app.cur_dir)
        end
    
        RunSynchronizedAQ(app,event)
        
    end
    methods
        function finishSyncAQ(app)
            app.camApp.ClosefileButton.ButtonPushedFcn(app,[]);
            app.Blue460nmButtonValueChanged
            app.Lime561nmButtonValueChanged
            app.Red640nmButtonValueChanged

        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            %
            % initialize hardware if not available already
            addpath Hardware\
            addpath Patterns\
            addpath Settings\
            addpath Waveforms\
            addpath Hadamard\
            try
                dragonflySettings
            catch
                edit dragonflySettings
                app.delete
                error('error running settings file')
            end
            try app.ni6343 = evalin('base','hDaq'); end %#okay no catch
            if isempty(app.ni6343) || ~isvalid(app.ni6343)
                fprintf('initializing NI daq ... \n')
                app.ni6343 = nidaq.getInstance(outChannelSettings,samplingRate,clockSettings);
                fprintf([8 'done\n'])
                assignin('base','hDaq',app.ni6343);
            end
            
            try app.limeLaser = evalin('base','hLimeLaser'); end %#okay no catch
            if isempty(app.limeLaser) || ~isvalid(app.limeLaser)
                try
                    app.limeLaser = mpbLaser(comPort); 
                catch me
                    warning(me.message)
                end
                assignin('base','hLimeLaser',app.limeLaser);
                
                app.limeLaser.turnOff
            end

%             app.DMDstatusButton.Value = true;
            
            try %#okay no catch
                app.hMotorsFig = evalin('base','hMotorsFig');
                app.motorZ = evalin('base','hMotorZ');
                app.motorW = evalin('base','hMotorW');
            end
            if  isempty(app.hMotorsFig) || ~isvalid(app.hMotorsFig) || ...
                isempty(app.motorZ)     || ~isvalid(app.motorZ)     || ...
                isempty(app.motorW)     || ~isvalid(app.motorW)
                fprintf('initializing motors ... \n');
                app.hMotorsFig = APTmotor.prepareFigure([-1414, 452, 500, 600]);
                app.motorZ = APTmotor   (ZmotorSerialNumber,[0, 301, 500, 300],app.hMotorsFig);
                app.motorW = filterWheel(WmotorSerialNumber,[0,   0, 500, 300],app.hMotorsFig);
                assignin('base','hMotorsFig',app.hMotorsFig)
                assignin('base','hMotorZ',app.motorZ)
                assignin('base','hMotorW',app.motorW)
                drawnow
                fprintf([8 'done\n']);
            end
            
            try app.camApp = evalin('base','hCameraApp'); end %#okay no catch
            if isempty(app.camApp) || ~isvalid(app.camApp)
                fprintf('initializing camera ... \n');
                app.camApp = cameraApp;
                fprintf('initializing camera ... done\n');
                assignin('base','hCameraApp',app.camApp);
            end
            
            try app.camApp = evalin('base','hODWheel'); end %#okay no catch
            if isempty(app.ODWheel) || ~isvalid(app.ODWheel)
                fprintf('initializing ODWheel ... \n');
                app.ODWheel = thorlabsFilterWheel(app.ni6343);
                fprintf('initializing ODWheel ... done\n');
                assignin('base','hODWheel',app.ODWheel);
            end
            
            assignin('base','hDragonflyApp',app);


            % run camera if not already running
%             try cameraMex startup, end % implements singleton instance
            
            % store internal properties that update on chage of controls only
%             app.liveCenteredROI = app.validateROI(app.ROIDropDown);
%             app.liveExposureTimeSeconds = app.ExposureTimeMsEditField.Value/1000;            
%             app.waveformCenteredROIInput = app.validateROI(app.ROIDropDown_waveform);
%             app.waveformExposureTimeSecondsInput = app.ExposureTimeMsEditField_waveform.Value/1000;            
%             app.waveformCameraFramesInput = app.camFramesInEditField.Value;            

            % load default gui settings from selected quick setting
            value = app.dfQuickSettingsDropDown.Value;
            fname = [value '.set'];
            app.readSettings(fname,true)
            app.updateSpacing
            app.logStartup
            app.FilterDropDownValueChanged
            app.WaveformLamp.Color = app.lampColor(false);
            app.PatternsLamp.Color = app.lampColor(false);
%             app.dragonflyAppUIFigure.Position = [1, 1, 940, 584];
%             app.camApp.cameraAppUIFigure.Position = [1, 1, 822, 188];
            
%             app.cur_dir = pwd;
            app.HadamardSequenceDropDown.Items = {'[11 3]','[19 4]','[27 6]','[35 10]','[59 09]','[63 14]'};
            app.dmd_pat = false(app.dmd.device.height,app.dmd.device.width);
            
            app.ActivateWaveformCameraFramesCheckBoxValueChanged;
            app.ActivateWaveformExposureTimeCheckBoxValueChanged;
            app.AcquisitionOptionsButtonGroupSelectionChanged;
% already there
            % go to live imaging mode
%             cameraMex('aq_live_restart',app.liveCenteredROI,app.liveExposureTimeSeconds)
            
        end

        % Close request function: dragonflyAppUIFigure
        function closeRequestFcn(app, event)
%             app.limeLaser.turnOff
            try
            app.ni6343.blueIntensity = 0;
            app.ni6343.redShutter = false;
            app.ni6343.limeShutter = false;
            app.ni6343.orangeShutter = false;
            app.ni6343.syncSession.release;
            end
%             try
%                 cameraMex shutdown
%                 clear cameraMex
%             end
%             close(app.hMotorsFig)
            delete(app)            
        end

        % Button pushed function: SavesettingsButton
        function SavesettingsButtonPushed(app, event)
            %
            %   2018-2019 Vicente Parot
            %   Cohen Lab - Harvard university
            %
            writeSettings(app)
        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            app.readSettings('',true)
            app.updateSpacing
            app.GotoSpinner.Limits = [1 app.NSpinner.Value];
            drawnow            
%             app.updateDMDStatusButton
            
        end

        % Value changed function: Blue460nmButton
        function Blue460nmButtonValueChanged(app, event)
            if app.Blue460nmButton.Value
                app.ni6343.blueIntensity = app.BlueAmpVEditField.Value;
            else
                app.ni6343.blueIntensity = 0;
            end
%                 vivid*.3+.9*.7
%                 blue [0.4,0.6902,1]*.3+.9*.7;
%                 lime [0.84,1, 0.25]*.3+.9*.7
%                 red  [1, 0.44,0.35]*.3+.9*.7

        end

        % Value changed function: dfQuickSettingsDropDown
        function dfQuickSettingsDropDownValueChanged(app, event)
            value = app.dfQuickSettingsDropDown.Value;
            fname = [value '.set'];
            app.readSettings(fname,true)
            app.updateSpacing
            app.GotoSpinner.Limits = [1 app.NSpinner.Value];
            drawnow            
%             app.updateDMDStatusButton
        end

        % Selection changed function: DMDlivedisplayButtonGroup
        function DMDlivedisplayButtonGroupSelectionChanged(app, event)
            app.PatternsLamp.Color = app.lampColor(false);
            selectedButton = app.DMDlivedisplayButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Register'
                    cur_bin = app.camApp.BinningDropDown.Value;
                    app.camApp.BinningDropDown.Value = '1 x 1';
                    app.camApp.BinningDropDown.ValueChangedFcn;

                    x_o=300;y_o=440;
                    regist_pat = register_pattern_test(app.dmd.device.height,app.dmd.device.width,[x_o,y_o]);
                    app.dmd.project(double(regist_pat))
                    
                    fig = uifigure;
                    selection=uiconfirm(fig,'Please focus', '',...
                                'option',{'finish'},'closefcn',@(h,e) close(fig));
       
                    img = app.snapOnePicture;
                    figure
                    imshow(img,[])
                    title('Please click outer corner of "L" in the sequence of 00 -> 10 -> 01. Press Enter to finish')
                    try 
                        [xi,yi] = getpts;
                    catch
                        app.camApp.BinningDropDown.Value = cur_bin;
                        app.camApp.BinningDropDown.ValueChangedFcn;
                        return
                    end
                    
                    if length(xi)<3,return;end
                    
                    xd00 = [x_o,y_o]'; xd01 = [x_o app.dmd.device.height-y_o]'; xd10 = [app.dmd.device.width-x_o y_o]';
                    xc00 = [xi(1),yi(1)]'; xc10 = [xi(2),yi(2)]'; xc01 = [xi(3),yi(3)]';
                    Xd_prm = [xd00-xd10 xd10-xd01]; Xc_prm = [xc00-xc10 xc10-xc01];
                    reg_rot_dil = Xc_prm/Xd_prm; reg_trans = xc00-reg_rot_dil*xd00;
                    vec = round([xi,yi]);
                    app.registration_data = [reg_trans reg_rot_dil]';
                    disp 'Registration Success!'
                    app.camApp.BinningDropDown.Value = cur_bin;
                    app.camApp.BinningDropDown.ValueChangedFcn;
                    
                case 'All ON'
                    app.dmd.all_on;
                    app.dmd_pat = true(app.dmd.device.height,app.dmd.device.width);
                case 'All OFF'
                    app.dmd.all_off;
                    app.dmd_pat = false(app.dmd.device.height,app.dmd.device.width);
                case 'Rectangle'
                    img = app.snapOnePicture;
                    fullCamImg = zeros(size(app.camApp.cameraMaskImg));
                    fullCamImg(app.camApp.cameraMaskImg) = imresize(img,app.camApp.liveBinning);
                    dmdImgTransposed = zeros(app.dmd.device.height,app.dmd.device.width);
                    dmdMaskTransposed = zeros(app.dmd.device.height,app.dmd.device.width);
                    heightOffset = (app.dmd.device.height-app.dmd.device.width)/2;
                    dmdImgTransposed(heightOffset + (1:app.dmd.device.width),:) = imresize(fullCamImg',[app.dmd.device.width app.dmd.device.width]);
                    figure
                    imshow(dmdImgTransposed',[])
                    try %#okay uncaught
                        myrect = getrect;   
                    end
                    if exist('myrect','var') && isnumeric(myrect) && numel(myrect)==4
                        line = myrect(1:2)+myrect(3:4).*[0 0 1 1 0; 0 1 1 0 0]';
                        hold on
                        plot(line(:,1),line(:,2))
                        hold off
%                         saveas(gcf,fullfile([datestr(now,'HHMMSS') ' define dmd.fig']))
                        dmdMaskTransposed(...
                            min(max(1,myrect(1)+(1:myrect(3))),end),...
                            min(max(1,myrect(2)+(1:myrect(4))),end)) = true;
                        figure
                        imshow(dmdMaskTransposed',[])
                        app.dmd.project(dmdMaskTransposed);
                        app.dmd_pat = dmdMaskTransposed;
                    end
                case 'Manual ROIs'
                    cur_bin = app.camApp.BinningDropDown.Value;
                    app.camApp.BinningDropDown.Value = '1 x 1';
                    app.camApp.BinningDropDown.ValueChangedFcn;
                    
                    fig = uifigure;
                    selection = uiconfirm(fig,'Would you like snapping or an extant figure?', '',...
                                'option',{'snap','figure'},'closefcn',@(h,e) close(fig));
                    switch selection
                        case 'snap'
                            img = app.snapOnePicture;
                            figure
                            imshow(img,[])
                        case 'figure'
                            fig = uifigure;
                            selection=uiconfirm(fig,'Please select desired figure', '',...
                            'option',{'finish'},'closefcn',@(h,e) close(fig));
                    end

                    hold on
                    title('Please clicky ROIs')
                    npts = 1;
                    nroi = 1;
                    while(npts > 0)
                         
                        [xv, yv] = (getline(gca, 'closed'));
                        if size(xv,1) < 3  % exit loop if only a line is drawn
                        break
                        end
                        
                        %draw the bounding polygons and label them
                        plot(xv, yv, 'Linewidth', 1);
                        
                        roi_points{nroi} = [xv, yv]+app.camApp.liveOffset';
                        nroi = nroi + 1;
                    end
                     
                     
                    if nroi>1
                        L = app.dmd.device.height;
                        W = app.dmd.device.width;
                        pat = false(L,W);
                        dmd_cam_trans = app.registration_data(1,:)';
                        dmd_rot_dil_mat = app.registration_data(2:3,:)';
                        [x, y] = meshgrid(1:W, 1:L);
                        dmd_pixel_pos = cellfun(@(xy) round(dmd_rot_dil_mat\(xy'-repmat(dmd_cam_trans,[1 length(xy)]))),roi_points,'uniformoutput',false);
                            
                        in = cellfun(@(pixel_pos) inpolygon(x,y,pixel_pos(1,:),pixel_pos(2,:)),dmd_pixel_pos,'uniformoutput',false);
                        for i=1:length(in), pat = pat|in{i}; end
                    end
                    
                    app.dmd.project(double(pat))
                    app.dmd_rois = dmd_pixel_pos;
                    app.dmd_pat = pat;
                    
                    app.camApp.BinningDropDown.Value = cur_bin;
                    app.camApp.BinningDropDown.ValueChangedFcn;
                case 'Semi-auto ROIs'
                    fig = uifigure;
                    selection = uiconfirm(fig,'Would you like to reconstruct Hadamard sequence?', 'Hadamard Reconstruction Option',...
                                'option',{'yes','no'},'closefcn',@(h,e) close(fig));
                    switch selection
                        case 'yes'
                            img = reconstruct_hadamard(str2num(app.HadamardSequenceDropDown.Value));
                            selection = uiconfirm(fig,'Would you like to proceed?', '',...
                                'option',{'yes','no'},'closefcn',@(h,e) close(fig));
                            if strcmp(selection,'no'), return; end
                            
                            img = im.CData;
                            hold on
                            rois = semiauto_roi_test(img);
                            
                            L = app.dmd.device.height;
                            W = app.dmd.device.width;
                            pat = false(L,W);
                                
                             dmd_cam_trans = app.registration_data(1,:)';
                            dmd_rot_dil_mat = app.registration_data(2:3,:)';
                            [x, y] = meshgrid(1:W, 1:L);
                            dmd_pixel_pos = cellfun(@(xy) round(dmd_rot_dil_mat\(xy'-repmat(dmd_cam_trans,[1 length(xy)]))),roi_points,'uniformoutput',false);
                                
                            in = cellfun(@(pixel_pos) inpolygon(x,y,pixel_pos(1,:),pixel_pos(2,:)),dmd_pixel_pos,'uniformoutput',false);
                            for i=1:lengnth(in), pat = pat|in{i}; end
                            

                        case 'no'
                            cur_bin = app.camApp.BinningDropDown.Value;
                            app.camApp.BinningDropDown.Value = '1 x 1';
                            app.camApp.BinningDropDown.ValueChangedFcn;
                            
%                             fig = uifigure;
%                             selection = uiconfirm(fig,'Would you like snapping or an extant figure?', '',...
%                                         'option',{'snap','figure'},'closefcn',@(h,e) close(fig));
%                             switch selection
%                                 case 'snap'
%                                     img = app.snapOnePicture;
%                                     figure
%                                     imshow(img,[])
%                                 case 'figure'
%                                     fig = uifigure;
%                                     uiconfirm(fig,'Please select desired figure', '',...
%                                     'option',{'finish'},'closefcn',@(h,e) close(fig));
%                                     img= get(gca,'children');
%                                     img=img.CData;
%                             end
%                             hold on
                            rois = app.cell_rois;
                            
                            L = app.dmd.device.height;
                            W = app.dmd.device.width;
                            pat = false(L,W);
                                
                             dmd_cam_trans = app.registration_data(1,:)';
                            dmd_rot_dil_mat = app.registration_data(2:3,:)';
                            [x, y] = meshgrid(1:W, 1:L);
                            dmd_pixel_pos = cellfun(@(xy) round(dmd_rot_dil_mat\((xy+app.camApp.liveOffset')'-repmat(dmd_cam_trans,[1 length(xy)]))),rois,'uniformoutput',false);
                            
                            in = cellfun(@(pixel_pos) inpolygon(x,y,pixel_pos(1,:),pixel_pos(2,:)),dmd_pixel_pos,'uniformoutput',false);
                            for i=1:length(in), pat = pat|in{i}; end
                            se = strel('disk',5,8);
                            pat = imdilate(pat,se);
                    end
                    
                                        
                    app.dmd.project(double(pat))
                    app.dmd_rois = dmd_pixel_pos;
                    app.dmd_pat = pat;
                    
                    app.camApp.BinningDropDown.Value = cur_bin;
                    app.camApp.BinningDropDown.ValueChangedFcn;
                case 'Focus Test'
                    answ = inputdlg('Input the size of the gap in pixels');
                    
                    dmd_cam_trans = app.registration_data(1,:)';
                    dmd_rot_dil_mat = app.registration_data(2:3,:)';
                    dmd_liveArea = app.camApp.liveArea/norm(dmd_rot_dil_mat*[1;0]);
                    dmd_liveOffset = (dmd_rot_dil_mat\(app.camApp.liveOffset-dmd_cam_trans));
                    test_p = dmd_focus_test(app.dmd,ceil(dmd_liveArea),ceil(dmd_liveOffset), str2num(answ{:}));
                    
                    app.dmd.project(double(test_p))
                    
                    
                otherwise
%                     disp 'unrecognized dmd command'
            end
        end

        % Value changed function: DMDstatusButton
        function DMDstatusButtonValueChanged(app, event)
            if app.DMDstatusButton.Value
                try app.dmd = evalin('base','hDMD'); end %#okay no catch
                if isempty(app.dmd) || ~isvalid(app.dmd)
                    app.dmd = vialuxControl.getInstance;
                    assignin('base','hDMD',app.dmd);
                end
            else
                evalin( 'base', 'clear hDMD' )
                if ~isempty(app.dmd) && isvalid(app.dmd)
                    app.dmd.delete;
                end
            end
            if ~isempty(app.dmd) && isvalid(app.dmd) && app.dmd.status
%                 app.AllOFFButton.Value = true;
                app.DMDstatusButton.Value = true;
                app.DMDlivedisplayButtonGroup.Visible = true;
                app.DMDlivedisplayButtonGroupSelectionChanged(event)
            else
                app.DMDstatusButton.Value = false;
                app.DMDlivedisplayButtonGroup.Visible = false;
            end            

        end

        % Value changed function: BlueAmpVEditField
        function BlueAmpVEditFieldValueChanged(app, event)
            if app.Blue460nmButton.Value
                app.ni6343.blueIntensity = app.BlueAmpVEditField.Value;
            else
                app.ni6343.blueIntensity = 0;
            end
            app.liveBluePowerEditField.Value = blueLUT_V_2_mWcm2(app.BlueAmpVEditField.Value);
        end

        % Value changed function: Lime561nmButton
        function Lime561nmButtonValueChanged(app, event)
            app.ni6343.limeShutter = app.Lime561nmButton.Value;
        end

        % Value changed function: LimePowerEditField
        function LimePowerEditFieldValueChanged(app, event)
            app.limeLaser.setPower(app.LimePowerEditField.Value)
        end

        % Value changed function: LimeLaserstatusButton
        function LimeLaserstatusButtonValueChanged(app, event)
            if app.LimeLaserstatusButton.Value
                app.limeLaser.turnOn
                app.limeLaser.setPower(app.LimePowerEditField.Value)
            else
                app.limeLaser.turnOff
            end
        end

        % Value changed function: Red640nmButton
        function Red640nmButtonValueChanged(app, event)
            app.ni6343.redShutter = app.Red640nmButton.Value;
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            app.motorW.switchTo(app.FilterDropDown.Value)
%             switch app.FilterDropDown.Value
%                 case 'GFP'
%                     app.SnapBlueONCheckBox.Value = true;
%                     app.SnapLimeONCheckBox.Value = false;
%                     app.SnapRedONCheckBox.Value = false;
%                 case 'RCaMP'
%                     app.SnapBlueONCheckBox.Value = false;
%                     app.SnapLimeONCheckBox.Value = true;
%                     app.SnapRedONCheckBox.Value = false;
%                 case 'Arch'
%                     app.SnapBlueONCheckBox.Value = false;
%                     app.SnapLimeONCheckBox.Value = false;
%                     app.SnapRedONCheckBox.Value = true;
%             end
        end

        % Callback function
        function ExposureTimeMsEditFieldValueChanged(app, event)
%             newValue = app.ExposureTimeMsEditField.Value/1000;
%             if ~isequal(newValue,app.liveExposureTimeSeconds)
%                 app.liveExposureTimeSeconds = newValue;
%                 cameraMex('aq_live_restart',app.liveCenteredROI,app.liveExposureTimeSeconds)
%             end
        end

        % Callback function
        function ROIDropDownValueChanged(app, event)
%             newValue = app.validateROI(app.ROIDropDown);
%             if ~isequal(newValue,app.liveExposureTimeSeconds)
%                 app.liveCenteredROI = newValue;
%                 cameraMex('aq_live_restart',app.liveCenteredROI,app.liveExposureTimeSeconds)
%             end
        end

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
            str_now = datestr(now,'HHMMSS');
            app.logImgParams(str_now)
            if app.ZstackButton.Value
                timeout = 2; % s
                originalPosition = app.GotoSpinner.Value;
                app.GotoSpinner.Editable = 'off';
                app.NSpinner.Editable = 'off';
                app.SetFirstZEditField.Editable = 'off';
                app.SetLastZEditField.Editable = 'off';
                drawnow
                mov = app.camApp.snapOneFrame;
                mov = zeros([size(mov) numel(app.stackZPositions)],'uint16');
                for it = 1:numel(app.stackZPositions)
                    app.GotoSpinner.Value = it;
                    app.motorZ.moveTo(app.stackZPositions(app.GotoSpinner.Value),timeout)
                    mov(:,:,it) = app.snapOnePicture;
                end
                app.GotoSpinner.Value = originalPosition;
                app.motorZ.moveTo(app.stackZPositions(originalPosition))
                app.GotoSpinner.Editable = 'on';
                app.NSpinner.Editable = 'on';
                app.SetFirstZEditField.Editable = 'on';
                app.SetLastZEditField.Editable = 'on';
                mov = vm(mov);
                figure
                moviefixsc(mov)
                saveastiff(uint16(mov),[str_now ' ' app.FilterDropDown.Value ' stack snap.tif']);
                assignin('base','mov',mov)
            else
                mov = app.snapOnePicture;
                figure(999);clf
                imshow(mov,[],'initialmagnification','fit')
                if ~isempty(app.SnapTextArea.Value{:})
                    imwrite(mov,fullfile([str_now app.SnapTextArea.Value{:} '.tif']))
                end
%                 imwrite(mov,fullfile([datestr(now,'HHMMSS') ' ' app.FilterDropDown.Value ' snap.tif']))
            end
            app.ZstackButton.Value = false;
        end

        % Value changed function: SpacingumEditField
        function SpacingumEditFieldValueChanged(app, event)
%             app.SpacingumEditField.Value;
            
        end

        % Value changed function: NSpinner
        function NSpinnerValueChanged(app, event)
            app.updateSpacing
            app.GotoSpinner.Limits = [1 app.NSpinner.Value];
            drawnow            
        end

        % Value changed function: SetFirstZEditField
        function SetFirstZEditFieldValueChanged(app, event)
            app.updateSpacing
        end

        % Value changed function: SetLastZEditField
        function SetLastZEditFieldValueChanged(app, event)
            app.updateSpacing
        end

        % Button pushed function: RunSynchronizedAQButton
        function RunSynchronizedAQButtonPushed(app, event)
%             app.logImgParams
%             if isempty(app.patternData)
%                 app.generatePatterns
%             end
            app.RunSynchronizedAQ(event)
 
        end

        % Value changed function: ActivateWaveformExposureTimeCheckBox
        function ActivateWaveformExposureTimeCheckBoxValueChanged(app, event)
            if app.ActivateWaveformExposureTimeCheckBox.Value
                app.ExposureTimeMsEditField_waveform.Enable = 'on';
                app.ExposureTimeMsEditField_waveformLabel.Enable = 'on';
            else
                app.ExposureTimeMsEditField_waveform.Enable = 'off';
                app.ExposureTimeMsEditField_waveformLabel.Enable = 'off';
            end
        end

        % Value changed function: ActivateWaveformROICheckBox
        function ActivateWaveformROICheckBoxValueChanged(app, event)
            if app.ActivateWaveformROICheckBox.Value
                app.ROIDropDown_waveform.Enable = 'on';
                app.ROIDropDown_waveformLabel.Enable = 'on';
            else
                app.ROIDropDown_waveform.Enable = 'off';
                app.ROIDropDown_waveformLabel.Enable = 'off';
            end           
        end

        % Value changed function: ActivateWaveformCameraFramesCheckBox
        function ActivateWaveformCameraFramesCheckBoxValueChanged(app, event)
            if app.ActivateWaveformCameraFramesCheckBox.Value
                app.camFramesInEditField.Enable = 'on';
                app.FramesEditFieldLabel.Enable = 'on';
            else
                app.camFramesInEditField.Enable = 'off';
                app.FramesEditFieldLabel.Enable = 'off';
            end                       
        end

        % Button pushed function: WaveformPreviewButton
        function WaveformPreviewButtonPushed(app, event)
            allWaves = app.generateWaveform;  %#okay not used
%             niDuration = app.queueWaveform;

%             figure windowstyle docked
%             plot(allWaves - [.1 .2 .3 .4 .5]')
%             legend 'camera trig' 'DMD trig' 'blue intensity' 'red shutter' 'lime shutter'    
        end

        % Value changed function: ROIDropDown_waveform
        function ROIDropDown_waveformValueChanged(app, event)
            app.waveformCenteredROIInput = app.validateROI(app.ROIDropDown_waveform);
        end

        % Value changed function: ExposureTimeMsEditField_waveform
        function ExposureTimeMsEditField_waveformValueChanged(app, event)
            app.waveformExposureTimeSecondsInput = app.ExposureTimeMsEditField_waveform.Value;            
        end

        % Value changed function: liveBluePowerEditField
        function liveBluePowerEditFieldValueChanged(app, event)
            app.BlueAmpVEditField.Value = blueLUT_mWcm2_2_V(app.liveBluePowerEditField.Value);            
            if app.Blue460nmButton.Value
                app.ni6343.blueIntensity = app.BlueAmpVEditField.Value;
            else
                app.ni6343.blueIntensity = 0;
            end
        end

        % Button pushed function: ShutdownButton
        function ShutdownButtonPushed(app, event)
%             cameraMex shutdown
            %-------- move snap logs ---------------
            if exist('Snap_logs','dir')
                
                try 
                    fname = dir('log*'); fname = fname.name;
                    movefile (fname,['Snap_logs/' fname]);
                end
            else
                mkdir('Snap_logs')
                try
                fname = dir('log*'); fname = fname.name;
                 movefile (fname,['Snap_logs/' fname]);
                end
            end
            %-------- move snap logs END---------------
            
            try %#okay no catch
                app.camApp.ShutdownButton.ButtonPushedFcn(app,event); 
            end
            try %#okay no catch
                app.LimeLaserstatusButton.Value = false;
                app.LimeLaserstatusButtonValueChanged;
                app.ODWheel.moveTo('0');
            end
            
            try
                app.motorW.switchTo('RCaMP')
            end
            evalin( 'base', 'clear hCameraApp' )
            evalin( 'base', 'clear hDaq' )
            evalin( 'base', 'clear hDMD' )
            evalin( 'base', 'clear hLimeLaser' )
            evalin( 'base', 'clear hODWheel' ) 
            try %#okay no catch
                evalin( 'base', 'close(hMotorsFig)' );
            end
            evalin( 'base', 'clear hMotorsFig' )
            evalin( 'base', 'clear hMotorZ' )
            evalin( 'base', 'clear hMotorW' )
            try %#okay no catch
                app.closeRequestFcn(event); 
            end 
            evalin( 'base', 'clear hDragonflyApp' )            
        end

        % Button pushed function: PatternsPreviewButton
        function PatternsPreviewButtonPushed(app, event)
            app.generatePatterns
%             app.loadPatterns

%             m = vm(alp_btd_to_logical(app.patternData));
%             figure windowstyle docked
%             moviefixsc(m)
%             figure windowstyle docked
%             plot(m.frameAverage)
        end

        % Value changed function: PatternsEditField
        function PatternsEditFieldValueChanged(app, event)
            app.PatternsLamp.Color = app.lampColor(false);
        end

        % Value changed function: WaveformEditField
        function WaveformEditFieldValueChanged(app, event)
            app.WaveformLamp.Color = app.lampColor(false);            
        end

        % Value changed function: GotoSpinner
        function GotoSpinnerValueChanged(app, event)
            app.GotoSpinner.Editable = 'off';
            drawnow
%             timeout = 2;
            app.motorZ.moveTo(app.stackZPositions(app.GotoSpinner.Value))
%             while app.motorZ.isMoving
%                 disp wait
%             end
            app.GotoSpinner.Editable = 'on';
        end

        % Button pushed function: SetFirstZButton
        function SetFirstZButtonPushed(app, event)
            app.SetFirstZEditField.Value = app.motorZ.position;
            app.updateSpacing
        end

        % Button pushed function: SetLastZButton_2
        function SetLastZButton_2Pushed(app, event)
            app.SetLastZEditField.Value = app.motorZ.position;
            app.updateSpacing
        end

        % Callback function
        function FolderButtonPushed(app, event)
            app.save_dir = uigetdir;
            app.SaveFolderTextArea.Value = app.save_dir;
        end

        % Selection changed function: AcquisitionOptionsButtonGroup
        function AcquisitionOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AcquisitionOptionsButtonGroup.SelectedObject;
            if iscell(selectedButton.Text), text = [selectedButton.Text{:} ];else, text = selectedButton.Text;end
            switch text
                case 'Regular'
                    app.WaveformEditField.Value='dragonflySimpleWaveform_yq';
                    app.PatternsEditField.Value = 'dragonflySimplePatterns_yq'; 
                case 'L1 Pattern'
                    app.WaveformEditField.Value='dragonflyL1PatternWaveform_yq';
                    app.PatternsEditField.Value = 'dragonflyL1PatternPatterns_yq'; 
                otherwise
                    app.WaveformEditField.Value='dragonflyHadamardWaveform_yq';
                    app.PatternsEditField.Value = 'dragonflyHadamardPatterns_yq'; 
            end
        end

        % Button pushed function: LoadCellROIsButton
        function LoadCellROIsButtonPushed(app, event)
            app.cell_rois = uiget_rois(app.camFramesInEditField.Value);
        end

        % Value changed function: Orange594nmButton
        function Orange594nmButtonValueChanged(app, event)
            app.ni6343.orangeShutter = app.Orange594nmButton.Value;
            
        end

        % Value changed function: PololuButton
        function PololuButtonValueChanged(app, event)
            app.ni6343.PololuTrigger = app.PololuButton.Value;
            
        end

        % Value changed function: ODDropDown
        function ODDropDownValueChanged(app, event)
            value = app.ODDropDown.Value;
            app.ODWheel.moveTo(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dragonflyAppUIFigure = uifigure;
            app.dragonflyAppUIFigure.Name = 'dragonflyApp';
            app.dragonflyAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequestFcn, true);
            app.DMDlivedisplayButtonGroup = uibuttongroup(app.dragonflyAppUIFigure);
            app.DMDlivedisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DMDlivedisplayButtonGroupSelectionChanged, true);
            app.DMDlivedisplayButtonGroup.Title = 'DMD live display';
            app.AllOFFButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.AllOFFButton.Text = 'All OFF';
            app.AllOFFButton.Value = true;
            app.AllONButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.AllONButton.Text = 'All ON';
            app.ManualROIsButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.ManualROIsButton.Text = 'Manual ROIs';
            app.RegisterButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.RegisterButton.Text = 'Register';
            app.SemiautoROIsButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.SemiautoROIsButton.Text = 'Semi-auto ROIs';
            app.AddROIsButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.AddROIsButton.Text = 'Add ROIs';
            app.DeleteROIsButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.DeleteROIsButton.Text = 'Delete ROIs';
            app.ResetButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.ResetButton.Text = 'Reset';
            app.RectangleButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.FocusTestButton = uitogglebutton(app.DMDlivedisplayButtonGroup);
            app.FocusTestButton.Text = 'Focus Test';
            app.DMDstatusButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.DMDstatusButton.ValueChangedFcn = createCallbackFcn(app, @DMDstatusButtonValueChanged, true);
            app.DMDstatusButton.Text = 'DMD status';
            app.SliceSpinnerLabel = uilabel(app.dragonflyAppUIFigure);
            app.SliceSpinnerLabel.Text = 'Slice #';
            app.SliceSpinner = uispinner(app.dragonflyAppUIFigure);
            app.SliceSpinner.Limits = [1 Inf];
            app.SliceSpinner.RoundFractionalValues = 'on';
            app.SliceSpinner.Value = 1;
            app.FOVSpinnerLabel = uilabel(app.dragonflyAppUIFigure);
            app.FOVSpinnerLabel.Text = 'FOV #';
            app.FOVSpinner = uispinner(app.dragonflyAppUIFigure);
            app.FOVSpinner.Limits = [1 Inf];
            app.FOVSpinner.RoundFractionalValues = 'on';
            app.FOVSpinner.Value = 1;
            app.appendedfoldernameEditFieldLabel = uilabel(app.dragonflyAppUIFigure);
            app.appendedfoldernameEditFieldLabel.Text = 'appended folder name';
            app.appendedfoldernameEditField = uieditfield(app.dragonflyAppUIFigure, 'text');
            app.appendedfoldernameEditField.Value = 'p1';
            app.RunSynchronizedAQButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.RunSynchronizedAQButton.ButtonPushedFcn = createCallbackFcn(app, @RunSynchronizedAQButtonPushed, true);
            app.RunSynchronizedAQButton.Text = 'Run synchronized AQ';
            app.SnapButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Text = 'Snap';
            app.NSpinnerLabel = uilabel(app.dragonflyAppUIFigure);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.dragonflyAppUIFigure);
            app.NSpinner.Limits = [2 Inf];
            app.NSpinner.RoundFractionalValues = 'on';
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @NSpinnerValueChanged, true);
            app.NSpinner.Value = 9;
            app.SpacingumEditFieldLabel = uilabel(app.dragonflyAppUIFigure);
            app.SpacingumEditFieldLabel.Text = 'Spacing (um)';
            app.SpacingumEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.SpacingumEditField.ValueChangedFcn = createCallbackFcn(app, @SpacingumEditFieldValueChanged, true);
            app.SpacingumEditField.Editable = 'off';
            app.SpacingumEditField.Value = 10;
            app.FilterDropDownLabel = uilabel(app.dragonflyAppUIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.dragonflyAppUIFigure);
            app.FilterDropDown.Items = {'Arch', 'GFP', 'RCaMP', 'RFP', 'CFP'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'RCaMP';
            app.SLMlivedisplayButtonGroup = uibuttongroup(app.dragonflyAppUIFigure);
            app.SLMlivedisplayButtonGroup.Title = 'SLM live display';
            app.CalibrationButton = uitogglebutton(app.SLMlivedisplayButtonGroup);
            app.CalibrationButton.Text = 'Calibration';
            app.CalibrationButton.Value = true;
            app.SheetButton = uitogglebutton(app.SLMlivedisplayButtonGroup);
            app.SheetButton.Text = 'Sheet';
            app.DotsButton_2 = uitogglebutton(app.SLMlivedisplayButtonGroup);
            app.DotsButton_2.Text = 'Dots';
            app.SLMstatusButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.SLMstatusButton.Text = 'SLM status';
            app.ZstackButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.ZstackButton.Text = 'Z-stack';
            app.BlueLabel = uilabel(app.dragonflyAppUIFigure);
            app.BlueLabel.Text = 'Blue';
            app.LimeLabel = uilabel(app.dragonflyAppUIFigure);
            app.LimeLabel.Text = 'Lime';
            app.SnapLimeONCheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.SnapLimeONCheckBox.Text = 'ON';
            app.SnapLimeOFFCheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.SnapLimeOFFCheckBox.Text = 'OFF';
            app.SnapLimeOFFCheckBox.Value = true;
            app.RedLabel = uilabel(app.dragonflyAppUIFigure);
            app.RedLabel.Text = 'Red';
            app.SnapRedONCheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.SnapRedONCheckBox.Text = 'ON';
            app.SnapRedOFFCheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.SnapRedOFFCheckBox.Text = 'OFF';
            app.SnapRedOFFCheckBox.Value = true;
            app.WithSnapLabel = uilabel(app.dragonflyAppUIFigure);
            app.WithSnapLabel.Text = 'With Snap:';
            app.Blue460nmButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.Blue460nmButton.ValueChangedFcn = createCallbackFcn(app, @Blue460nmButtonValueChanged, true);
            app.Blue460nmButton.Text = {'Blue'; '460 nm'};
            app.Lime561nmButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.Lime561nmButton.ValueChangedFcn = createCallbackFcn(app, @Lime561nmButtonValueChanged, true);
            app.Lime561nmButton.Text = {'Lime'; '561 nm'};
            app.Red640nmButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.Red640nmButton.ValueChangedFcn = createCallbackFcn(app, @Red640nmButtonValueChanged, true);
            app.Red640nmButton.Text = {'Red'; '640 nm'};
            app.SavesettingsButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.LoadsettingsButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.SnapBlueONCheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.SnapBlueONCheckBox.Text = 'ON';
            app.SnapBlueOFFCheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.SnapBlueOFFCheckBox.Text = 'OFF';
            app.SnapBlueOFFCheckBox.Value = true;
            app.QuickSettingsDropDownLabel = uilabel(app.dragonflyAppUIFigure);
            app.QuickSettingsDropDownLabel.Text = 'Quick Settings';
            app.dfQuickSettingsDropDown = uidropdown(app.dragonflyAppUIFigure);
            app.dfQuickSettingsDropDown.Items = {'Optopatch', 'Hadamard', 'RCaMPtopatch', 'SimpleMovie'};
            app.dfQuickSettingsDropDown.ValueChangedFcn = createCallbackFcn(app, @dfQuickSettingsDropDownValueChanged, true);
            app.dfQuickSettingsDropDown.Value = 'RCaMPtopatch';
            app.AmpVLabel = uilabel(app.dragonflyAppUIFigure);
            app.AmpVLabel.Text = 'Amp (V)';
            app.BlueAmpVEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.BlueAmpVEditField.Limits = [0 10];
            app.BlueAmpVEditField.ValueDisplayFormat = '%.2f';
            app.BlueAmpVEditField.ValueChangedFcn = createCallbackFcn(app, @BlueAmpVEditFieldValueChanged, true);
            app.BlueAmpVEditField.Value = 0.1;
            app.PowermWLabel = uilabel(app.dragonflyAppUIFigure);
            app.PowermWLabel.Text = 'Power (mW)';
            app.LimePowerEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.LimePowerEditField.Limits = [100 1000];
            app.LimePowerEditField.RoundFractionalValues = 'on';
            app.LimePowerEditField.ValueChangedFcn = createCallbackFcn(app, @LimePowerEditFieldValueChanged, true);
            app.LimePowerEditField.Value = 100;
            app.LimeLaserstatusButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.LimeLaserstatusButton.ValueChangedFcn = createCallbackFcn(app, @LimeLaserstatusButtonValueChanged, true);
            app.LimeLaserstatusButton.Text = 'Laser status';
            app.GotoSpinnerLabel = uilabel(app.dragonflyAppUIFigure);
            app.GotoSpinnerLabel.Text = 'Go to';
            app.GotoSpinner = uispinner(app.dragonflyAppUIFigure);
            app.GotoSpinner.Limits = [1 9];
            app.GotoSpinner.RoundFractionalValues = 'on';
            app.GotoSpinner.ValueChangedFcn = createCallbackFcn(app, @GotoSpinnerValueChanged, true);
            app.GotoSpinner.Value = 5;
            app.SetFirstZEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.SetFirstZEditField.ValueDisplayFormat = '%11.6g';
            app.SetFirstZEditField.ValueChangedFcn = createCallbackFcn(app, @SetFirstZEditFieldValueChanged, true);
            app.SetFirstZEditField.Value = 13.57;
            app.SetLastZEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.SetLastZEditField.ValueDisplayFormat = '%11.6g';
            app.SetLastZEditField.ValueChangedFcn = createCallbackFcn(app, @SetLastZEditFieldValueChanged, true);
            app.SetLastZEditField.Value = 13.65;
            app.WaveformEditField = uieditfield(app.dragonflyAppUIFigure, 'text');
            app.WaveformEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformEditFieldValueChanged, true);
            app.WaveformEditField.Value = 'dragonflySimpleWaveform_yq';
            app.FramesEditFieldLabel = uilabel(app.dragonflyAppUIFigure);
            app.FramesEditFieldLabel.Enable = 'off';
            app.FramesEditFieldLabel.Text = 'Frames';
            app.camFramesInEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.camFramesInEditField.Limits = [1 Inf];
            app.camFramesInEditField.RoundFractionalValues = 'on';
            app.camFramesInEditField.ValueDisplayFormat = '%d';
            app.camFramesInEditField.Enable = 'off';
            app.camFramesInEditField.Value = 1;
            app.PicturesLabel = uilabel(app.dragonflyAppUIFigure);
            app.PicturesLabel.Text = 'Pictures';
            app.camPicturesOutEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.camPicturesOutEditField.Limits = [0 Inf];
            app.camPicturesOutEditField.RoundFractionalValues = 'on';
            app.camPicturesOutEditField.ValueDisplayFormat = '%d';
            app.camPicturesOutEditField.Editable = 'off';
            app.WaveformPreviewButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.WaveformPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @WaveformPreviewButtonPushed, true);
            app.WaveformPreviewButton.Text = 'Generate';
            app.ExposureTimeMsEditField_waveformLabel = uilabel(app.dragonflyAppUIFigure);
            app.ExposureTimeMsEditField_waveformLabel.Enable = 'off';
            app.ExposureTimeMsEditField_waveformLabel.Text = 'Exposure time (ms)';
            app.ExposureTimeMsEditField_waveform = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.ExposureTimeMsEditField_waveform.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeMsEditField_waveformValueChanged, true);
            app.ExposureTimeMsEditField_waveform.Enable = 'off';
            app.ExposureTimeMsEditField_waveform.Value = 150;
            app.ROIDropDown_waveformLabel = uilabel(app.dragonflyAppUIFigure);
            app.ROIDropDown_waveformLabel.Enable = 'off';
            app.ROIDropDown_waveformLabel.Text = 'ROI';
            app.ROIDropDown_waveform = uidropdown(app.dragonflyAppUIFigure);
            app.ROIDropDown_waveform.Items = {'2048 x 2048', '1024 x 1024', '512 x 512', '256 x 256', '2048 x 160', '1024 x 160'};
            app.ROIDropDown_waveform.Editable = 'on';
            app.ROIDropDown_waveform.ValueChangedFcn = createCallbackFcn(app, @ROIDropDown_waveformValueChanged, true);
            app.ROIDropDown_waveform.Enable = 'off';
            app.ROIDropDown_waveform.Value = '1024 x 1024';
            app.ActivateWaveformExposureTimeCheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.ActivateWaveformExposureTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivateWaveformExposureTimeCheckBoxValueChanged, true);
            app.ActivateWaveformExposureTimeCheckBox.Text = '';
            app.ActivateWaveformExposureTimeCheckBox.Value = true;
            app.ActivateWaveformROICheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.ActivateWaveformROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivateWaveformROICheckBoxValueChanged, true);
            app.ActivateWaveformROICheckBox.Text = '';
            app.ActivateWaveformCameraFramesCheckBox = uicheckbox(app.dragonflyAppUIFigure);
            app.ActivateWaveformCameraFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivateWaveformCameraFramesCheckBoxValueChanged, true);
            app.ActivateWaveformCameraFramesCheckBox.Text = '';
            app.ActivateWaveformCameraFramesCheckBox.Value = true;
            app.nCellsEditFieldLabel = uilabel(app.dragonflyAppUIFigure);
            app.nCellsEditFieldLabel.Text = 'nCells';
            app.nCellsEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.nCellsEditField.Limits = [1 Inf];
            app.nCellsEditField.RoundFractionalValues = 'on';
            app.nCellsEditField.ValueDisplayFormat = '%d';
            app.nCellsEditField.Value = 1;
            app.mWcm2Label = uilabel(app.dragonflyAppUIFigure);
            app.mWcm2Label.Text = '(mW/cm2)';
            app.liveBluePowerEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.liveBluePowerEditField.Limits = [0 Inf];
            app.liveBluePowerEditField.ValueDisplayFormat = '%.2f';
            app.liveBluePowerEditField.ValueChangedFcn = createCallbackFcn(app, @liveBluePowerEditFieldValueChanged, true);
            app.liveBluePowerEditField.Value = 8.8;
            app.ShutdownButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.ShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownButtonPushed, true);
            app.ShutdownButton.Text = 'Shutdown';
            app.PatternsEditField = uieditfield(app.dragonflyAppUIFigure, 'text');
            app.PatternsEditField.ValueChangedFcn = createCallbackFcn(app, @PatternsEditFieldValueChanged, true);
            app.PatternsEditField.Value = 'dragonflySimplePatterns_yq';
            app.PatternsLampLabel = uilabel(app.dragonflyAppUIFigure);
            app.PatternsLampLabel.Text = 'Patterns';
            app.PatternsLamp = uilamp(app.dragonflyAppUIFigure);
            app.WaveformLampLabel = uilabel(app.dragonflyAppUIFigure);
            app.WaveformLampLabel.Text = 'Waveform';
            app.WaveformLamp = uilamp(app.dragonflyAppUIFigure);
            app.PatternsPreviewButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.PatternsPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PatternsPreviewButtonPushed, true);
            app.PatternsPreviewButton.Text = 'Generate';
            app.PatternsLabel = uilabel(app.dragonflyAppUIFigure);
            app.PatternsLabel.Text = 'Patterns';
            app.dmdPatternsOutEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.dmdPatternsOutEditField.Limits = [0 Inf];
            app.dmdPatternsOutEditField.RoundFractionalValues = 'on';
            app.dmdPatternsOutEditField.ValueDisplayFormat = '%d';
            app.dmdPatternsOutEditField.Editable = 'off';
            app.DMDTriggersLabel = uilabel(app.dragonflyAppUIFigure);
            app.DMDTriggersLabel.Text = 'DMD Triggers';
            app.dmdTrigsOutEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.dmdTrigsOutEditField.Limits = [0 Inf];
            app.dmdTrigsOutEditField.RoundFractionalValues = 'on';
            app.dmdTrigsOutEditField.ValueDisplayFormat = '%d';
            app.dmdTrigsOutEditField.Editable = 'off';
            app.SetFirstZButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.SetFirstZButton.ButtonPushedFcn = createCallbackFcn(app, @SetFirstZButtonPushed, true);
            app.SetFirstZButton.Text = 'Set First Z';
            app.SetLastZButton_2 = uibutton(app.dragonflyAppUIFigure, 'push');
            app.SetLastZButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetLastZButton_2Pushed, true);
            app.SetLastZButton_2.Text = 'Set Last Z';
            app.AcquisitionOptionsButtonGroup = uibuttongroup(app.dragonflyAppUIFigure);
            app.AcquisitionOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AcquisitionOptionsButtonGroupSelectionChanged, true);
            app.AcquisitionOptionsButtonGroup.Title = 'Acquisition Options';
            app.L1PatternButton_2 = uiradiobutton(app.AcquisitionOptionsButtonGroup);
            app.L1PatternButton_2.Text = 'L1 Pattern';
            app.HadamardButton = uiradiobutton(app.AcquisitionOptionsButtonGroup);
            app.HadamardButton.Text = 'Hadamard';
            app.RegularButton = uiradiobutton(app.AcquisitionOptionsButtonGroup);
            app.RegularButton.Text = 'Regular';
            app.RegularButton.Value = true;
            app.HadamardSequenceDropDownLabel = uilabel(app.dragonflyAppUIFigure);
            app.HadamardSequenceDropDownLabel.Text = {'Hadamard'; 'Sequence'};
            app.HadamardSequenceDropDown = uidropdown(app.dragonflyAppUIFigure);
            app.FrametimemsEditFieldLabel = uilabel(app.dragonflyAppUIFigure);
            app.FrametimemsEditFieldLabel.Text = 'Frame time (ms)';
            app.FrametimemsEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.FrametimemsEditField.Editable = 'off';
            app.IlluminationColorDropDownLabel = uilabel(app.dragonflyAppUIFigure);
            app.IlluminationColorDropDownLabel.Text = 'Illumination Color';
            app.IlluminationColorDropDown = uidropdown(app.dragonflyAppUIFigure);
            app.IlluminationColorDropDown.Items = {'Blue', 'Lime', 'Red'};
            app.IlluminationColorDropDown.Value = 'Blue';
            app.LoadCellROIsButton = uibutton(app.dragonflyAppUIFigure, 'push');
            app.LoadCellROIsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCellROIsButtonPushed, true);
            app.LoadCellROIsButton.Text = 'Load Cell ROIs';
            app.Orange594nmButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.Orange594nmButton.ValueChangedFcn = createCallbackFcn(app, @Orange594nmButtonValueChanged, true);
            app.Orange594nmButton.Text = {'Orange'; '594 nm'};
            app.RepeatEditFieldLabel = uilabel(app.dragonflyAppUIFigure);
            app.RepeatEditFieldLabel.Text = 'Repeat';
            app.RepeatEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.RepeatEditField.Value = 1;
            app.WaitTimesEditFieldLabel = uilabel(app.dragonflyAppUIFigure);
            app.WaitTimesEditFieldLabel.Text = 'Wait Time (s)';
            app.WaitTimesEditField = uieditfield(app.dragonflyAppUIFigure, 'numeric');
            app.SnapTextArea = uitextarea(app.dragonflyAppUIFigure);
            app.PololuButton = uibutton(app.dragonflyAppUIFigure, 'state');
            app.PololuButton.ValueChangedFcn = createCallbackFcn(app, @PololuButtonValueChanged, true);
            app.PololuButton.Text = {'Pololu'; ''};
            app.ODDropDownLabel = uilabel(app.dragonflyAppUIFigure);
            app.ODDropDownLabel.Text = 'OD';
            app.ODDropDown = uidropdown(app.dragonflyAppUIFigure);
            app.ODDropDown.Items = {'0', '0.5', '1', '2', '3', '4'};
            app.ODDropDown.ValueChangedFcn = createCallbackFcn(app, @ODDropDownValueChanged, true);
            app.ODDropDown.Value = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dragonflyApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dragonflyAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dragonflyAppUIFigure)
        end
    end
end