classdef dmd_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DMDControlButtonGroup           matlab.ui.container.ButtonGroup
        RegisterButton                  matlab.ui.control.ToggleButton
        AllOnButton                     matlab.ui.control.ToggleButton
        AllOffButton                    matlab.ui.control.ToggleButton
        ProjectPatternButton            matlab.ui.control.ToggleButton
        RegistrationLabel               matlab.ui.control.Label
        QuickOptionsLabel               matlab.ui.control.Label
        HorizontalOffsetEditFieldLabel  matlab.ui.control.Label
        HorizontalOffsetEditField       matlab.ui.control.NumericEditField
        VerticalOffsetEditFieldLabel    matlab.ui.control.Label
        VerticalOffsetEditField         matlab.ui.control.NumericEditField
        MaskNoTotalEditFieldLabel       matlab.ui.control.Label
        MaskNoTotalEditField            matlab.ui.control.NumericEditField
        MaskSeqPreviewButton            matlab.ui.control.Button
        LoadReferenceImageButton        matlab.ui.control.Button
        DMDMaskGenButtonGroup           matlab.ui.container.ButtonGroup
        ArbShapeButton                  matlab.ui.control.ToggleButton
        RectangleButton                 matlab.ui.control.ToggleButton
        CircleNAButton                  matlab.ui.control.ToggleButton
        LoadPictureNAButton             matlab.ui.control.ToggleButton
        DMDSeqUpdateButtonGroup         matlab.ui.container.ButtonGroup
        AppendButton                    matlab.ui.control.ToggleButton
        DeleteButton                    matlab.ui.control.ToggleButton
        ReorderButton                   matlab.ui.control.ToggleButton
        SaveButton                      matlab.ui.control.ToggleButton
        LoadButton                      matlab.ui.control.ToggleButton
        DMDMaskGenButton                matlab.ui.control.Button
        DMDSeqUpdateButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        dmd
        
        registration_data
        
        current_mask
        
        mask_sequence
        
        fig_mask_seq_preview % Description
        fig_mask_draw
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath dmd_control\
            
            try app.dmd = evalin('base','hDMD');end
            try app.registration_data = load('dmd_registration_data.txt');end
            if isempty(app.dmd) || ~isvalid(app.dmd)
                app.dmd = vialuxDMDControl.getInstance;
                assignin('base','hDMDApp',app);
            end
            
%             dmd_register(app) % use existing registration file
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.dmd) && isvalid(app.dmd)
                app.dmd.delete;
            end
            evalin('base','clear hDMDApp')
            delete(app)
            
        end

        % Selection changed function: DMDControlButtonGroup
        function DMDControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.DMDControlButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Project Pattern'
                    dmd_register(app)
                case 'Register'
                    dmd_register(app)
                case 'All On'
                    app.dmd.all_on
                case 'All Off'
                    app.dmd.all_off
            end
        end

        % Button pushed function: DMDMaskGenButton
        function DMDMaskGenButtonPushed(app, event)
            selectedButton = app.DMDMaskGenButtonGroup.SelectedObject;
            
            app.current_mask = dmd_draw(app,selectedButton.Text);
        end

        % Button pushed function: DMDSeqUpdateButton
        function DMDSeqUpdateButtonPushed(app, event)
            selectedButton = app.DMDSeqUpdateButtonGroup.SelectedObject;
            
            app.mask_sequence = dmd_set_sequence(app,selectedButton.Text);
        end

        % Button pushed function: MaskSeqPreviewButton
        function MaskSeqPreviewButtonPushed(app, event)
            if isempty(app.fig_mask_seq_preview)
                app.fig_mask_seq_preview = figure;
                moviesc(app.mask_sequence)
            else 
                figure(app.fig_mask_seq_preview);clf
                moviesc(app.mask_sequence)
            end
        end

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            app.fig_mask_draw = dmd_load_reference_img(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DMDControlButtonGroup = uibuttongroup(app.UIFigure);
            app.DMDControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DMDControlButtonGroupSelectionChanged, true);
            app.DMDControlButtonGroup.Title = 'DMD Control';
            app.RegisterButton = uitogglebutton(app.DMDControlButtonGroup);
            app.RegisterButton.Text = 'Register';
            app.AllOnButton = uitogglebutton(app.DMDControlButtonGroup);
            app.AllOnButton.Text = 'All On';
            app.AllOffButton = uitogglebutton(app.DMDControlButtonGroup);
            app.AllOffButton.Text = 'All Off';
            app.AllOffButton.Value = true;
            app.ProjectPatternButton = uitogglebutton(app.DMDControlButtonGroup);
            app.ProjectPatternButton.Text = 'Project Pattern';
            app.RegistrationLabel = uilabel(app.DMDControlButtonGroup);
            app.RegistrationLabel.Text = 'Registration';
            app.QuickOptionsLabel = uilabel(app.DMDControlButtonGroup);
            app.QuickOptionsLabel.Text = 'Quick Options';
            app.HorizontalOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalOffsetEditFieldLabel.Text = 'Horizontal Offset';
            app.HorizontalOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalOffsetEditFieldLabel.Text = 'Vertical Offset';
            app.VerticalOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaskNoTotalEditFieldLabel = uilabel(app.UIFigure);
            app.MaskNoTotalEditFieldLabel.Text = 'Mask No. Total';
            app.MaskNoTotalEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaskNoTotalEditField.Editable = 'off';
            app.MaskSeqPreviewButton = uibutton(app.UIFigure, 'push');
            app.MaskSeqPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @MaskSeqPreviewButtonPushed, true);
            app.MaskSeqPreviewButton.Text = 'Mask Seq Preview';
            app.LoadReferenceImageButton = uibutton(app.UIFigure, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.DMDMaskGenButtonGroup = uibuttongroup(app.UIFigure);
            app.DMDMaskGenButtonGroup.Title = 'DMD Mask Gen';
            app.ArbShapeButton = uitogglebutton(app.DMDMaskGenButtonGroup);
            app.ArbShapeButton.Text = 'Arb Shape';
            app.RectangleButton = uitogglebutton(app.DMDMaskGenButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.RectangleButton.Value = true;
            app.CircleNAButton = uitogglebutton(app.DMDMaskGenButtonGroup);
            app.CircleNAButton.Text = 'Circle (NA)';
            app.LoadPictureNAButton = uitogglebutton(app.DMDMaskGenButtonGroup);
            app.LoadPictureNAButton.Text = 'Load Picture (NA)';
            app.DMDSeqUpdateButtonGroup = uibuttongroup(app.UIFigure);
            app.DMDSeqUpdateButtonGroup.Title = 'DMD Seq Update';
            app.AppendButton = uitogglebutton(app.DMDSeqUpdateButtonGroup);
            app.AppendButton.Text = 'Append';
            app.AppendButton.Value = true;
            app.DeleteButton = uitogglebutton(app.DMDSeqUpdateButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.ReorderButton = uitogglebutton(app.DMDSeqUpdateButtonGroup);
            app.ReorderButton.Text = 'Reorder';
            app.SaveButton = uitogglebutton(app.DMDSeqUpdateButtonGroup);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uitogglebutton(app.DMDSeqUpdateButtonGroup);
            app.LoadButton.Text = 'Load';
            app.DMDMaskGenButton = uibutton(app.UIFigure, 'push');
            app.DMDMaskGenButton.ButtonPushedFcn = createCallbackFcn(app, @DMDMaskGenButtonPushed, true);
            app.DMDMaskGenButton.Text = 'DMD Mask Gen';
            app.DMDSeqUpdateButton = uibutton(app.UIFigure, 'push');
            app.DMDSeqUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @DMDSeqUpdateButtonPushed, true);
            app.DMDSeqUpdateButton.Text = 'DMD Seq Update';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dmd_control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end