classdef slm_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SLMControlButtonGroup           matlab.ui.container.ButtonGroup
        RegisterButton                  matlab.ui.control.ToggleButton
        ProjectPatternButton            matlab.ui.control.ToggleButton
        RegistrationLabel               matlab.ui.control.Label
        ResetButton                     matlab.ui.control.ToggleButton
        LiveRegisterButton              matlab.ui.control.ToggleButton
        HorizontalOffsetEditFieldLabel  matlab.ui.control.Label
        HorizontalOffsetEditField       matlab.ui.control.NumericEditField
        VerticalOffsetEditFieldLabel    matlab.ui.control.Label
        VerticalOffsetEditField         matlab.ui.control.NumericEditField
        LoadReferenceImageButton        matlab.ui.control.Button
        SLMMaskGenButtonGroup           matlab.ui.container.ButtonGroup
        ArbShapeButton                  matlab.ui.control.ToggleButton
        RectangleButton                 matlab.ui.control.ToggleButton
        CircleNAButton                  matlab.ui.control.ToggleButton
        LoadPictureButton               matlab.ui.control.ToggleButton
        SLMMaskGenButton                matlab.ui.control.Button
        SLMSpotsGenButtonGroup          matlab.ui.container.ButtonGroup
        ArbShapeButton_2                matlab.ui.control.ToggleButton
        RectangleButton_2               matlab.ui.control.ToggleButton
        SpotsButton                     matlab.ui.control.ToggleButton
        LoadPictureNAButton_2           matlab.ui.control.ToggleButton
        SLMSpotsGenButton               matlab.ui.control.Button
        AddLensButton                   matlab.ui.control.Button
        fmmEditFieldLabel               matlab.ui.control.Label
        fmmEditField                    matlab.ui.control.NumericEditField
        CurrentMaskButton               matlab.ui.control.Button
        DrawROIsButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        SLM
        
        registration_data
        
        current_rois
        current_mask
        
        mask_sequence
        
        fig_mask_seq_preview % Description
        fig_mask_draw
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath slm_control\
            addpath slm_control\control\
            try
                app.SLM = evalin('base','hSLM');
            catch
                app.SLM = slm_device;
                assignin('base','hSLM',app.SLM)
            end
            try app.registration_data = load('slm_registration_data.txt');end
            assignin('base','hSLMApp',app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            evalin('base','clear hSLMApp')
            delete(app)
            
        end

        % Selection changed function: SLMControlButtonGroup
        function SLMControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.SLMControlButtonGroup.SelectedObject;
            
            slm_register(app);
        end

        % Button pushed function: SLMMaskGenButton
        function SLMMaskGenButtonPushed(app, event)
            selectedButton = app.SLMMaskGenButtonGroup.SelectedObject;
            
            app.current_mask = slm_draw(app,selectedButton.Text,'Area');
        end

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            app.fig_mask_draw = slm_load_reference_img(app);
        end

        % Button pushed function: SLMSpotsGenButton
        function SLMSpotsGenButtonPushed(app, event)
            selectedButton = app.SLMSpotsGenButtonGroup.SelectedObject;
            
            app.current_mask = slm_draw(app,selectedButton.Text,'Spots');

        end

        % Button pushed function: AddLensButton
        function AddLensButtonPushed(app, event)
            add_quadratic_phase(app)
        end

        % Button pushed function: CurrentMaskButton
        function CurrentMaskButtonPushed(app, event)
            project_current_mask(app)
        end

        % Button pushed function: DrawROIsButton
        function DrawROIsButtonPushed(app, event)
            slm_draw_rois(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SLMControlButtonGroup = uibuttongroup(app.UIFigure);
            app.SLMControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SLMControlButtonGroupSelectionChanged, true);
            app.SLMControlButtonGroup.Title = 'SLM Control';
            app.RegisterButton = uitogglebutton(app.SLMControlButtonGroup);
            app.RegisterButton.Text = 'Register';
            app.RegisterButton.Value = true;
            app.ProjectPatternButton = uitogglebutton(app.SLMControlButtonGroup);
            app.ProjectPatternButton.Text = 'Project Pattern';
            app.RegistrationLabel = uilabel(app.SLMControlButtonGroup);
            app.RegistrationLabel.Text = 'Registration';
            app.ResetButton = uitogglebutton(app.SLMControlButtonGroup);
            app.ResetButton.Text = 'Reset';
            app.LiveRegisterButton = uitogglebutton(app.SLMControlButtonGroup);
            app.LiveRegisterButton.Text = 'Live Register';
            app.HorizontalOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalOffsetEditFieldLabel.Text = 'Horizontal Offset';
            app.HorizontalOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalOffsetEditFieldLabel.Text = 'Vertical Offset';
            app.VerticalOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadReferenceImageButton = uibutton(app.UIFigure, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.SLMMaskGenButtonGroup = uibuttongroup(app.UIFigure);
            app.SLMMaskGenButtonGroup.Title = 'SLM Mask Gen';
            app.ArbShapeButton = uitogglebutton(app.SLMMaskGenButtonGroup);
            app.ArbShapeButton.Text = 'Arb Shape';
            app.RectangleButton = uitogglebutton(app.SLMMaskGenButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.RectangleButton.Value = true;
            app.CircleNAButton = uitogglebutton(app.SLMMaskGenButtonGroup);
            app.CircleNAButton.Text = 'Circle (NA)';
            app.LoadPictureButton = uitogglebutton(app.SLMMaskGenButtonGroup);
            app.LoadPictureButton.Text = 'Load Picture';
            app.SLMMaskGenButton = uibutton(app.UIFigure, 'push');
            app.SLMMaskGenButton.ButtonPushedFcn = createCallbackFcn(app, @SLMMaskGenButtonPushed, true);
            app.SLMMaskGenButton.Text = 'SLM Mask Gen';
            app.SLMSpotsGenButtonGroup = uibuttongroup(app.UIFigure);
            app.SLMSpotsGenButtonGroup.Title = 'SLM Spots Gen';
            app.ArbShapeButton_2 = uitogglebutton(app.SLMSpotsGenButtonGroup);
            app.ArbShapeButton_2.Text = 'Arb Shape';
            app.ArbShapeButton_2.Value = true;
            app.RectangleButton_2 = uitogglebutton(app.SLMSpotsGenButtonGroup);
            app.RectangleButton_2.Text = 'Rectangle';
            app.SpotsButton = uitogglebutton(app.SLMSpotsGenButtonGroup);
            app.SpotsButton.Text = 'Spots';
            app.LoadPictureNAButton_2 = uitogglebutton(app.SLMSpotsGenButtonGroup);
            app.LoadPictureNAButton_2.Text = 'Load Picture (NA)';
            app.SLMSpotsGenButton = uibutton(app.UIFigure, 'push');
            app.SLMSpotsGenButton.ButtonPushedFcn = createCallbackFcn(app, @SLMSpotsGenButtonPushed, true);
            app.SLMSpotsGenButton.Text = 'SLM Spots Gen';
            app.AddLensButton = uibutton(app.UIFigure, 'push');
            app.AddLensButton.ButtonPushedFcn = createCallbackFcn(app, @AddLensButtonPushed, true);
            app.AddLensButton.Text = 'Add Lens';
            app.fmmEditFieldLabel = uilabel(app.UIFigure);
            app.fmmEditFieldLabel.Text = 'f (mm)';
            app.fmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentMaskButton = uibutton(app.UIFigure, 'push');
            app.CurrentMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentMaskButtonPushed, true);
            app.CurrentMaskButton.Text = 'Current Mask';
            app.DrawROIsButton = uibutton(app.UIFigure, 'push');
            app.DrawROIsButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIsButtonPushed, true);
            app.DrawROIsButton.Text = 'Draw ROIs';
        end
    end

    methods (Access = public)

        % Construct app
        function app = slm_control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end