classdef slm_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SLMControlButtonGroup           matlab.ui.container.ButtonGroup
        RegisterButton                  matlab.ui.control.ToggleButton
        ProjectPatternButton            matlab.ui.control.ToggleButton
        RegistrationLabel               matlab.ui.control.Label
        HorizontalOffsetEditFieldLabel  matlab.ui.control.Label
        HorizontalOffsetEditField       matlab.ui.control.NumericEditField
        VerticalOffsetEditFieldLabel    matlab.ui.control.Label
        VerticalOffsetEditField         matlab.ui.control.NumericEditField
        LoadReferenceImageButton        matlab.ui.control.Button
        SLMMaskGenButtonGroup           matlab.ui.container.ButtonGroup
        ArbShapeButton                  matlab.ui.control.ToggleButton
        RectangleButton                 matlab.ui.control.ToggleButton
        CircleNAButton                  matlab.ui.control.ToggleButton
        LoadPictureNAButton             matlab.ui.control.ToggleButton
        SLMMaskGenButton                matlab.ui.control.Button
        SLMSpotsGenButtonGroup          matlab.ui.container.ButtonGroup
        ArbShapeButton_2                matlab.ui.control.ToggleButton
        RectangleButton_2               matlab.ui.control.ToggleButton
        SpotsButton                     matlab.ui.control.ToggleButton
        LoadPictureNAButton_2           matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)
        dmd
        
        registration_data
        
        current_mask
        
        mask_sequence
        
        fig_mask_seq_preview % Description
        fig_mask_draw
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath slm_control\
            addpath slm_control\control\
            app.SLM = slm_device;
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.SLM) 
                app.SLM.clearSDK
            end
        
            delete(app)
            
        end

        % Selection changed function: SLMControlButtonGroup
        function SLMControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.SLMControlButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Project Pattern'
                    slm_register(app)
                case 'Register'
                    slm_register(app)
            end
        end

        % Button pushed function: SLMMaskGenButton
        function SLMMaskGenButtonPushed(app, event)
            selectedButton = app.SLMMaskGenButtonGroup.SelectedObject;
            
            app.current_mask = SLM_draw(app,selectedButton.Text,'area');
        end

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            app.fig_mask_draw = slm_load_reference_img;
        end

        % Selection changed function: SLMSpotsGenButtonGroup
        function SLMSpotsGenButtonGroupSelectionChanged(app, event)
            selectedButton = app.SLMSpotsGenButtonGroup.SelectedObject;
            app.current_mask = SLM_draw(app,selectedButton.Text,'spt');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SLMControlButtonGroup = uibuttongroup(app.UIFigure);
            app.SLMControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SLMControlButtonGroupSelectionChanged, true);
            app.SLMControlButtonGroup.Title = 'SLM Control';
            app.RegisterButton = uitogglebutton(app.SLMControlButtonGroup);
            app.RegisterButton.Text = 'Register';
            app.RegisterButton.Value = true;
            app.ProjectPatternButton = uitogglebutton(app.SLMControlButtonGroup);
            app.ProjectPatternButton.Text = 'Project Pattern';
            app.RegistrationLabel = uilabel(app.SLMControlButtonGroup);
            app.RegistrationLabel.Text = 'Registration';
            app.HorizontalOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalOffsetEditFieldLabel.Text = 'Horizontal Offset';
            app.HorizontalOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalOffsetEditFieldLabel.Text = 'Vertical Offset';
            app.VerticalOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadReferenceImageButton = uibutton(app.UIFigure, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.SLMMaskGenButtonGroup = uibuttongroup(app.UIFigure);
            app.SLMMaskGenButtonGroup.Title = 'SLM Mask Gen';
            app.ArbShapeButton = uitogglebutton(app.SLMMaskGenButtonGroup);
            app.ArbShapeButton.Text = 'Arb Shape';
            app.RectangleButton = uitogglebutton(app.SLMMaskGenButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.RectangleButton.Value = true;
            app.CircleNAButton = uitogglebutton(app.SLMMaskGenButtonGroup);
            app.CircleNAButton.Text = 'Circle (NA)';
            app.LoadPictureNAButton = uitogglebutton(app.SLMMaskGenButtonGroup);
            app.LoadPictureNAButton.Text = 'Load Picture (NA)';
            app.SLMMaskGenButton = uibutton(app.UIFigure, 'push');
            app.SLMMaskGenButton.ButtonPushedFcn = createCallbackFcn(app, @SLMMaskGenButtonPushed, true);
            app.SLMMaskGenButton.Text = 'SLM Mask Gen';
            app.SLMSpotsGenButtonGroup = uibuttongroup(app.UIFigure);
            app.SLMSpotsGenButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SLMSpotsGenButtonGroupSelectionChanged, true);
            app.SLMSpotsGenButtonGroup.Title = 'SLM Spots Gen';
            app.ArbShapeButton_2 = uitogglebutton(app.SLMSpotsGenButtonGroup);
            app.ArbShapeButton_2.Text = 'Arb Shape';
            app.ArbShapeButton_2.Value = true;
            app.RectangleButton_2 = uitogglebutton(app.SLMSpotsGenButtonGroup);
            app.RectangleButton_2.Text = 'Rectangle';
            app.SpotsButton = uitogglebutton(app.SLMSpotsGenButtonGroup);
            app.SpotsButton.Text = 'Spots';
            app.LoadPictureNAButton_2 = uitogglebutton(app.SLMSpotsGenButtonGroup);
            app.LoadPictureNAButton_2.Text = 'Load Picture (NA)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = slm_control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end